classdef ConvertMonoToStereo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectTheChannelsUIFigure  matlab.ui.Figure
        CreateButton               matlab.ui.control.Button
        Channel2DropDown           matlab.ui.control.DropDown
        Channel2DropDownLabel      matlab.ui.control.Label
        Channel1DropDown           matlab.ui.control.DropDown
        Channel1DropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        signal
        channel1 = 'Channel 1 S1';
        channel2 = 'Channel 1 S1';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, signal)
            movegui(app.SelectTheChannelsUIFigure, 'center');
            app.CallingApp = caller;
            app.signal = signal;
        end

        % Value changed function: Channel1DropDown
        function Channel1DropDownValueChanged(app, event)
            app.channel1 = app.Channel1DropDown.Value;
        end

        % Value changed function: Channel2DropDown
        function Channel2DropDownValueChanged(app, event)
            app.channel2 = app.Channel2DropDown.Value;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            monoStereo(app.CallingApp, app.signal, app.channel1, app.channel2);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectTheChannelsUIFigure = uifigure('Visible', 'off');
            app.SelectTheChannelsUIFigure.Name = 'Select The Channels';
            app.Channel1DropDownLabel = uilabel(app.SelectTheChannelsUIFigure);
            app.Channel1DropDownLabel.Text = 'Channel 1';
            app.Channel1DropDown = uidropdown(app.SelectTheChannelsUIFigure);
            app.Channel1DropDown.Items = {'Channel 1 S1', 'Channel 2 S1', 'Out 1 S1', 'Out 2 S1', 'Channel 1 S2', 'Channel 2 S2', 'Out 1 S2', 'Out 2 S2', 'Result 1', 'Result 2', 'Empty'};
            app.Channel1DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel1DropDownValueChanged, true);
            app.Channel1DropDown.Value = 'Channel 1 S1';
            app.Channel2DropDownLabel = uilabel(app.SelectTheChannelsUIFigure);
            app.Channel2DropDownLabel.Text = 'Channel 2';
            app.Channel2DropDown = uidropdown(app.SelectTheChannelsUIFigure);
            app.Channel2DropDown.Items = {'Channel 1 S1', 'Channel 2 S1', 'Out 1 S1', 'Out 2 S1', 'Channel 1 S2', 'Channel 2 S2', 'Out 1 S2', 'Out 2 S2', 'Result 1', 'Result 2', 'Empty'};
            app.Channel2DropDown.ValueChangedFcn = createCallbackFcn(app, @Channel2DropDownValueChanged, true);
            app.Channel2DropDown.Value = 'Channel 1 S1';
            app.CreateButton = uibutton(app.SelectTheChannelsUIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            % Show the figure after all components are created
            app.SelectTheChannelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvertMonoToStereo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectTheChannelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectTheChannelsUIFigure)
        end
    end
end