classdef ConvertStereoToMono < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectTheOptionsUIFigure  matlab.ui.Figure
        CreateButton              matlab.ui.control.Button
        OptionsDropDown           matlab.ui.control.DropDown
        OptionsDropDownLabel      matlab.ui.control.Label
        SavetoDropDown            matlab.ui.control.DropDown
        SavetoDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        signal
        saveTo = 'Channel 1';
        options = 'Average Ch1 & Ch2';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, signal)
            movegui(app.SelectTheOptionsUIFigure, 'center');
            app.CallingApp = caller;
            app.signal = signal;
        end

        % Value changed function: SavetoDropDown
        function SavetoDropDownValueChanged(app, event)
            app.saveTo = app.SavetoDropDown.Value;
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            app.options = app.OptionsDropDown.Value;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            stereoMono(app.CallingApp, app.signal, app.saveTo, app.options);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectTheOptionsUIFigure = uifigure('Visible', 'off');
            app.SelectTheOptionsUIFigure.Name = 'Select The Options';
            app.SavetoDropDownLabel = uilabel(app.SelectTheOptionsUIFigure);
            app.SavetoDropDownLabel.Text = 'Save to';
            app.SavetoDropDown = uidropdown(app.SelectTheOptionsUIFigure);
            app.SavetoDropDown.Items = {'Channel 1', 'Channel 2'};
            app.SavetoDropDown.ValueChangedFcn = createCallbackFcn(app, @SavetoDropDownValueChanged, true);
            app.SavetoDropDown.Value = 'Channel 1';
            app.OptionsDropDownLabel = uilabel(app.SelectTheOptionsUIFigure);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.SelectTheOptionsUIFigure);
            app.OptionsDropDown.Items = {'Average Ch1 & Ch2', 'Keep Channel 1 S1', 'Keep Channel 2 S1', 'Keep Out 1 S1', 'Keep Out 2 S1', 'Keep Channel 1 S2', 'Keep Channel 2 S2', 'Keep Out 1 S2', 'Keep Out 2 S2', 'Keep Result 1', 'Keep Result 2'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Average Ch1 & Ch2';
            app.CreateButton = uibutton(app.SelectTheOptionsUIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            % Show the figure after all components are created
            app.SelectTheOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvertStereoToMono(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectTheOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectTheOptionsUIFigure)
        end
    end
end