classdef CreateSignal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateSyntheticSignalUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        xnTextArea                     matlab.ui.control.TextArea
        xnTextAreaLabel                matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        CloseButton                    matlab.ui.control.Button
        Savetosignal2Button            matlab.ui.control.Button
        Savetosignal1Button            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        EnterthecoefficientsseparatedbyLabel  matlab.ui.control.Label
        AmplitudEditField              matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditField   matlab.ui.control.NumericEditField
        ZeroInstantPositionEditField   matlab.ui.control.NumericEditField
        InstantZeroLabel               matlab.ui.control.Label
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        AmplitudEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        amplitude = 1;   % Amplitud
        Fs = 1;        % Frecuencia digital
        in = 1;       % Instante inicial
        n = [];          % Tiempo
        x = [1,2,3,4,5]; % Señal
    end
    
    methods (Access = public)
        
        function graph(app)
            app.Fs = app.SamplingFrequencyHzEditField.Value;
            app.x = app.x*app.AmplitudEditField.Value;
            app.in = app.ZeroInstantPositionEditField.Value;

            elementosAntes = app.in - 1;
            elementosDespues = length(app.x) - app.in;
            
            app.n = -elementosAntes:elementosDespues;           

            stem(app.UIAxes, app.n, app.x);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.CreateSyntheticSignalUIFigure, 'center');
            app.CallingApp = caller;
            app.graph();
        end

        % Value changed function: AmplitudEditField
        function AmplitudEditFieldValueChanged(app, event)
            app.amplitude = app.AmplitudEditField.Value;
            app.graph();
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyHzEditField.Value;
            if value <= 0
                msgbox('Invalid value.', 'Error', 'error');
                app.SamplingFrequencyHzEditField.Value = app.Fs;
                return;
            else
                app.Fs = value;
            end
            app.graph();
        end

        % Value changed function: ZeroInstantPositionEditField
        function ZeroInstantPositionEditFieldValueChanged(app, event)
            if app.ZeroInstantPositionEditField.Value >= 1 && app.ZeroInstantPositionEditField.Value <= length(app.x)
                app.in = app.ZeroInstantPositionEditField.Value;
                app.graph();
            else
                msgbox('invalid position.', 'Error', 'error');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: Savetosignal1Button
        function Savetosignal1ButtonPushed(app, event)
            select = questdlg('Select the channel to save', ' Select Channel', ...
                'Channel 1', 'Channel 2', 'Cancel', ...
                'Channel 1');
            switch select
                case 'Channel 1'
                    graphSignal1(app.CallingApp, app.n, app.x, 0, app.Fs);
                case 'Channel 2'
                    graphSignal1(app.CallingApp, app.n, app.x, 1, app.Fs);
                case 'Cancel'
            end
        end

        % Button pushed function: Savetosignal2Button
        function Savetosignal2ButtonPushed(app, event)
            select = questdlg('Select the channel to save', ' Select Channel', ...
                'Channel 1', 'Channel 2', 'Cancel', ...
                'Channel 1');
            switch select
                case 'Channel 1'
                    graphSignal2(app.CallingApp, app.n, app.x, 0, app.Fs);
                case 'Channel 2'
                    graphSignal2(app.CallingApp, app.n, app.x, 1, app.Fs);
                case 'Cancel'
            end
        end

        % Value changed function: xnTextArea
        function xnTextAreaValueChanged(app, event)
            try
                % Obtener el valor del TextArea
                value = char(app.xnTextArea.Value);
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end

            % Verificar si el valor está vacío
            if isempty(value)
                % Manejar el caso de valor vacío
                msgbox('Enter values in the TextArea.', 'Error', 'error');
                return;
            end

            % Convertir a un arreglo
            try
                celulasNumeros = strsplit(value, ';');
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end

            % Verificar si todos los elementos son números válidos (incluyendo notación científica)
            if all(cellfun(@(x) ~isempty(x) && all(isstrprop(x, 'digit') | x == '-' | x == '.' | x == 'e' | x == 'E'), celulasNumeros))
                % Convertir a números
                app.x = str2double(celulasNumeros);
                % Llamar a la función de actualización del gráfico (si es necesario)
                app.graph();
            else
                % Manejar el caso de caracteres no válidos
                msgbox('Enter only numbers separated by semicolons in scientific notation.', 'Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateSyntheticSignalUIFigure = uifigure('Visible', 'off');
            app.CreateSyntheticSignalUIFigure.Name = 'Create Synthetic Signal';
            app.GridLayout = uigridlayout(app.CreateSyntheticSignalUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout2);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.GridLayout2);
            app.SamplingFrequencyHzEditFieldLabel.Text = {'Sampling'; 'Frequency (Hz)'};
            app.InstantZeroLabel = uilabel(app.GridLayout2);
            app.InstantZeroLabel.Text = {'Zero Instant'; 'Position'};
            app.ZeroInstantPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZeroInstantPositionEditField.ValueChangedFcn = createCallbackFcn(app, @ZeroInstantPositionEditFieldValueChanged, true);
            app.ZeroInstantPositionEditField.Value = 1;
            app.SamplingFrequencyHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.SamplingFrequencyHzEditField.Value = 1;
            app.AmplitudEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudEditFieldValueChanged, true);
            app.AmplitudEditField.Value = 1;
            app.EnterthecoefficientsseparatedbyLabel = uilabel(app.GridLayout2);
            app.EnterthecoefficientsseparatedbyLabel.Text = 'Enter the coefficients separated by ";".';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'x(n)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Savetosignal1Button = uibutton(app.GridLayout4, 'push');
            app.Savetosignal1Button.ButtonPushedFcn = createCallbackFcn(app, @Savetosignal1ButtonPushed, true);
            app.Savetosignal1Button.Text = 'Save to signal 1';
            app.Savetosignal2Button = uibutton(app.GridLayout4, 'push');
            app.Savetosignal2Button.ButtonPushedFcn = createCallbackFcn(app, @Savetosignal2ButtonPushed, true);
            app.Savetosignal2Button.Text = 'Save to signal 2';
            app.CloseButton = uibutton(app.GridLayout4, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.xnTextAreaLabel = uilabel(app.GridLayout3);
            app.xnTextAreaLabel.Text = 'x(n)';
            app.xnTextArea = uitextarea(app.GridLayout3);
            app.xnTextArea.ValueChangedFcn = createCallbackFcn(app, @xnTextAreaValueChanged, true);
            app.xnTextArea.Value = {'1;2;3;4;5'};
            % Show the figure after all components are created
            app.CreateSyntheticSignalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateSignal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateSyntheticSignalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateSyntheticSignalUIFigure)
        end
    end
end