classdef CreateSyntheticSignalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateSyntheticSignalUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        CloseButton                     matlab.ui.control.Button
        Savetosignal2Button             matlab.ui.control.Button
        Savetosignal1Button             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        FinalFrecuencyHzEditField       matlab.ui.control.NumericEditField
        FinalFrecuencyHzEditFieldLabel  matlab.ui.control.Label
        PhasedegreesEditField           matlab.ui.control.NumericEditField
        PhasedegreesEditFieldLabel      matlab.ui.control.Label
        SignalDropDown                  matlab.ui.control.DropDown
        GainEditField                   matlab.ui.control.NumericEditField
        AnalogFrequencyHzEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        SignalDropDownLabel             matlab.ui.control.Label
        ShitfEditFieldLabel             matlab.ui.control.Label
        InitialInstantEditField         matlab.ui.control.NumericEditField
        FinalInstantEditField           matlab.ui.control.NumericEditField
        ShitfEditField                  matlab.ui.control.NumericEditField
        FinalInstantEditFieldLabel      matlab.ui.control.Label
        InitialInstantEditFieldLabel    matlab.ui.control.Label
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        AnalogFrequencyHzEditFieldLabel  matlab.ui.control.Label
        GainEditFieldLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        amplitude = 1;   % Amplitud
        F1 = 20;         % Frecuencia analogica
        Fs = 400;        % Frecuencia digital
        shitf = 0;       % Desplazamiento
        phase = 0;       % Fase
        in = -100;       % Instante inicial
        fn = 100;        % Instante final
        n = [];          % Tiempo
        x = [];          % Señal
        type = 0;        % Control tipo de señal a graficar
    end
    
    methods (Access = public)
        
        function graph(app,type)
            app.Fs = app.SamplingFrequencyHzEditField.Value;
            app.n = app.in:app.fn;
            w = 2*pi*app.F1/app.Fs;
            switch type
                case 0
                    app.x = app.amplitude*sin(w*(app.n - app.shitf) + app.phase*pi/180);
                    stem(app.UIAxes, app.n, app.x);
                case 1
                    app.x = app.amplitude*cos(w*(app.n - app.shitf) + app.phase*pi/180);
                    stem(app.UIAxes, app.n, app.x);                    
                case 2
                    % Generar el vector de tiempo
                    t = (app.in*(1/app.Fs)):1/app.Fs:(app.fn*(1/app.Fs));
                    % Determinar el tiempo de la señal:
                    if app.in < 0
                        T = (abs(app.in) + abs(app.fn))*(1/app.Fs);
                    else
                        T = (abs(app.fn) - abs(app.in))*(1/app.Fs);
                    end
                    % Graficar señal:
                    app.x = app.amplitude*chirp(t, app.AnalogFrequencyHzEditField.Value, T, ...
                        app.FinalFrecuencyHzEditField.Value, 'linear', (app.phase*pi/180));
                    app.n = (app.in - app.shitf):1:(app.fn - app.shitf);
                    % Visualizar la señal chirp
                    stem(app.UIAxes, app.n, app.x);
                case 3
                    app.Fs = 1;
                    app.x = app.amplitude*[zeros(1,abs(app.in)+app.shitf), ones(1, abs(app.fn)+1-app.shitf)];
                    stem(app.UIAxes, app.n, app.x);                  
                case 4
                    app.Fs = 1;
                    app.x = app.amplitude*(app.n - app.shitf);
                    stem(app.UIAxes, app.n, app.x);
                case 5
                    % Intervalo de tiempo:
                    t = (app.in*(1/app.Fs)):1/app.Fs:(app.fn*(1/app.Fs));
                    % Generar la onda diente de sierra:
                    app.x = ((app.amplitude/2)*sawtooth((2*pi*app.F1*t) + app.phase*pi/180)) + (app.amplitude/2);
                    app.n = (app.in - app.shitf):1:(app.fn - app.shitf);
                    stem(app.UIAxes, app.n, app.x);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, typeSignal)
            movegui(app.CreateSyntheticSignalUIFigure, 'center');
            app.CallingApp = caller;
            switch typeSignal
                case 0
                    app.SignalDropDown.Value = 'Sine';
                case 1
                    app.SignalDropDown.Value = 'Cosine';
                case 2
                    app.SignalDropDown.Value = 'Chirp';
                case 3
                    app.SignalDropDown.Value = 'Step';
                case 4
                    app.SignalDropDown.Value = 'Ramp';
                case 5
                    app.SignalDropDown.Value = 'Sawtooth';
            end
            SignalDropDownValueChanged(app);
            app.graph(typeSignal);
        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            valueList = app.SignalDropDown.Value;
            
            switch valueList
                case 'Sine'
                    % Habilitar o deshabilitar campos
                    app.GainEditFieldLabel.Text = "Gain";
                    app.AnalogFrequencyHzEditFieldLabel.Text = sprintf('Analog\nFrequency (Hz)');
                    app.AnalogFrequencyHzEditField.Enable = "on";
                    app.SamplingFrequencyHzEditField.Enable = "on";
                    app.PhasedegreesEditField.Enable = "on";
                    app.FinalFrecuencyHzEditField.Visible = "off";
                    app.FinalFrecuencyHzEditFieldLabel.Visible = "off";
                    app.AnalogFrequencyHzEditField.Value = 20;
                    app.SamplingFrequencyHzEditField.Value = 400;
                    % Graficar
                    app.type = 0;
                    app.graph(app.type);
                case 'Cosine'
                    % Habilitar o deshabilitar campos
                    app.GainEditFieldLabel.Text = "Gain";
                    app.AnalogFrequencyHzEditFieldLabel.Text = sprintf('Analog\nFrequency (Hz)');
                    app.AnalogFrequencyHzEditField.Enable = "on";
                    app.SamplingFrequencyHzEditField.Enable = "on";
                    app.PhasedegreesEditField.Enable = "on";
                    app.FinalFrecuencyHzEditField.Visible = "off";
                    app.FinalFrecuencyHzEditFieldLabel.Visible = "off";
                    app.AnalogFrequencyHzEditField.Value = 20;
                    app.SamplingFrequencyHzEditField.Value = 400;
                    % Graficar
                    app.type = 1;
                    app.graph(app.type);               
                case 'Chirp'
                    % Habilitar o deshabilitar campos
                    app.GainEditFieldLabel.Text = "Gain";
                    app.AnalogFrequencyHzEditFieldLabel.Text = sprintf('Initial\nFrequency (Hz)');
                    app.AnalogFrequencyHzEditField.Enable = "on";
                    app.SamplingFrequencyHzEditField.Enable = "on";
                    app.PhasedegreesEditField.Enable = "on";
                    app.FinalFrecuencyHzEditField.Visible = "on";
                    app.FinalFrecuencyHzEditFieldLabel.Visible = "on";
                    %Valores por defecto:
                    app.AnalogFrequencyHzEditField.Value = 1;
                    app.FinalFrecuencyHzEditField.Value = 10;
                    app.SamplingFrequencyHzEditField.Value = 100;
                    % Graficar
                    app.type = 2;
                    app.graph(app.type);
                case 'Step'
                    % Habilitar o deshabilitar campos
                    app.GainEditFieldLabel.Text = "Amplitude";
                    app.AnalogFrequencyHzEditFieldLabel.Text = sprintf('Analog\nFrequency (Hz)');
                    app.AnalogFrequencyHzEditField.Enable = "off";
                    app.SamplingFrequencyHzEditField.Enable = "off";
                    app.PhasedegreesEditField.Enable = "off";
                    app.FinalFrecuencyHzEditField.Visible = "off";
                    app.FinalFrecuencyHzEditFieldLabel.Visible = "off";
                    app.AnalogFrequencyHzEditField.Value = 20;
                    app.SamplingFrequencyHzEditField.Value = 400;
                    % Graficar
                    app.type = 3;
                    app.graph(app.type);              
                case 'Ramp'
                    % Habilitar o deshabilitar campos
                    app.GainEditFieldLabel.Text = "Slope";
                    app.AnalogFrequencyHzEditFieldLabel.Text = sprintf('Analog\nFrequency (Hz)');
                    app.AnalogFrequencyHzEditField.Enable = "off";
                    app.SamplingFrequencyHzEditField.Enable = "off";
                    app.PhasedegreesEditField.Enable = "off";
                    app.FinalFrecuencyHzEditField.Visible = "off";
                    app.FinalFrecuencyHzEditFieldLabel.Visible = "off";
                    app.AnalogFrequencyHzEditField.Value = 20;
                    app.SamplingFrequencyHzEditField.Value = 400;
                    % Graficar
                    app.type = 4;
                    app.graph(app.type);
                case 'Sawtooth'
                    % Habilitar o deshabilitar campos
                    app.GainEditFieldLabel.Text = "Amplitude";
                    app.AnalogFrequencyHzEditFieldLabel.Text = sprintf('Analog\nFrequency (Hz)');
                    app.AnalogFrequencyHzEditField.Enable = "on";
                    app.SamplingFrequencyHzEditField.Enable = "on";
                    app.FinalFrecuencyHzEditField.Visible = "off";
                    app.FinalFrecuencyHzEditFieldLabel.Visible = "off";
                    app.AnalogFrequencyHzEditField.Value = 20;
                    app.SamplingFrequencyHzEditField.Value = 800;
                    % Graficar
                    app.type = 5;
                    app.graph(app.type);
            end
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            app.amplitude = app.GainEditField.Value;
            app.graph(app.type);
        end

        % Value changed function: AnalogFrequencyHzEditField
        function AnalogFrequencyHzEditFieldValueChanged(app, event)
            value = app.AnalogFrequencyHzEditField.Value;
            if value < 0
                msgbox('Invalid value.', 'Error', 'error');
                app.AnalogFrequencyHzEditField.Value = app.F1;
            else
                app.F1 = value;
                app.graph(app.type);
            end
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyHzEditField.Value;
            if value <= 0
                msgbox('Invalid value.', 'Error', 'error');
                app.SamplingFrequencyHzEditField.Value = app.Fs;
                return;
            else
                app.Fs = value;
            end
            app.graph(app.type);
        end

        % Value changed function: InitialInstantEditField
        function InitialInstantEditFieldValueChanged(app, event)
            app.in = app.InitialInstantEditField.Value;
            app.graph(app.type);
        end

        % Value changed function: FinalInstantEditField
        function FinalInstantEditFieldValueChanged(app, event)
            app.fn = app.FinalInstantEditField.Value;
            app.graph(app.type);
        end

        % Value changed function: ShitfEditField
        function ShitfEditFieldValueChanged(app, event)
            app.shitf = app.ShitfEditField.Value;
            app.graph(app.type);
        end

        % Value changed function: PhasedegreesEditField
        function PhasedegreesEditFieldValueChanged(app, event)
            app.phase = app.PhasedegreesEditField.Value;
            app.graph(app.type);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: Savetosignal1Button
        function Savetosignal1ButtonPushed(app, event)
            select = questdlg('Select the channel to save', ' Select Channel', ...
                'Channel 1', 'Channel 2', 'Cancel', ...
                'Channel 1');
            switch select
                case 'Channel 1'
                    graphSignal1(app.CallingApp, app.n, app.x, 0, app.Fs);
                case 'Channel 2'
                    graphSignal1(app.CallingApp, app.n, app.x, 1, app.Fs);
                case 'Cancel'
            end
        end

        % Button pushed function: Savetosignal2Button
        function Savetosignal2ButtonPushed(app, event)
            select = questdlg('Select the channel to save', ' Select Channel', ...
                'Channel 1', 'Channel 2', 'Cancel', ...
                'Channel 1');
            switch select
                case 'Channel 1'
                    graphSignal2(app.CallingApp, app.n, app.x, 0, app.Fs);
                case 'Channel 2'
                    graphSignal2(app.CallingApp, app.n, app.x, 1, app.Fs);
                case 'Cancel'
            end
        end

        % Value changed function: FinalFrecuencyHzEditField
        function FinalFrecuencyHzEditFieldValueChanged(app, event)
            value = app.FinalFrecuencyHzEditField.Value;
            if value <= 0
                msgbox('Invalid value.', 'Error', 'error');
                app.FinalFrecuencyHzEditField.Value = app.AnalogFrequencyHzEditField.Value + 10;
                graph(app, app.type);
                return;
            else
                graph(app, app.type);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateSyntheticSignalUIFigure = uifigure('Visible', 'off');
            app.CreateSyntheticSignalUIFigure.Name = 'Create Synthetic Signal';
            app.GridLayout = uigridlayout(app.CreateSyntheticSignalUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GainEditFieldLabel = uilabel(app.GridLayout2);
            app.GainEditFieldLabel.Text = 'Gain';
            app.AnalogFrequencyHzEditFieldLabel = uilabel(app.GridLayout2);
            app.AnalogFrequencyHzEditFieldLabel.Text = {'Analog'; 'Frequency (Hz)'};
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.GridLayout2);
            app.SamplingFrequencyHzEditFieldLabel.Text = {'Sampling'; 'Frequency (Hz)'};
            app.InitialInstantEditFieldLabel = uilabel(app.GridLayout2);
            app.InitialInstantEditFieldLabel.Text = 'Initial Instant';
            app.FinalInstantEditFieldLabel = uilabel(app.GridLayout2);
            app.FinalInstantEditFieldLabel.Text = 'Final Instant';
            app.ShitfEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ShitfEditField.ValueChangedFcn = createCallbackFcn(app, @ShitfEditFieldValueChanged, true);
            app.FinalInstantEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FinalInstantEditField.ValueChangedFcn = createCallbackFcn(app, @FinalInstantEditFieldValueChanged, true);
            app.FinalInstantEditField.Value = 100;
            app.InitialInstantEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InitialInstantEditField.ValueChangedFcn = createCallbackFcn(app, @InitialInstantEditFieldValueChanged, true);
            app.InitialInstantEditField.Value = -100;
            app.ShitfEditFieldLabel = uilabel(app.GridLayout2);
            app.ShitfEditFieldLabel.Text = 'Shitf';
            app.SignalDropDownLabel = uilabel(app.GridLayout2);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SamplingFrequencyHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.SamplingFrequencyHzEditField.Value = 400;
            app.AnalogFrequencyHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AnalogFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @AnalogFrequencyHzEditFieldValueChanged, true);
            app.AnalogFrequencyHzEditField.Value = 20;
            app.GainEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 1;
            app.SignalDropDown = uidropdown(app.GridLayout2);
            app.SignalDropDown.Items = {'Sine', 'Cosine', 'Chirp', 'Step', 'Ramp', 'Sawtooth'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = 'Sine';
            app.PhasedegreesEditFieldLabel = uilabel(app.GridLayout2);
            app.PhasedegreesEditFieldLabel.Text = 'Phase (degrees)';
            app.PhasedegreesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PhasedegreesEditField.ValueChangedFcn = createCallbackFcn(app, @PhasedegreesEditFieldValueChanged, true);
            app.FinalFrecuencyHzEditFieldLabel = uilabel(app.GridLayout2);
            app.FinalFrecuencyHzEditFieldLabel.Visible = 'off';
            app.FinalFrecuencyHzEditFieldLabel.Text = {'Final'; 'Frecuency (Hz)'};
            app.FinalFrecuencyHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FinalFrecuencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FinalFrecuencyHzEditFieldValueChanged, true);
            app.FinalFrecuencyHzEditField.Visible = 'off';
            app.FinalFrecuencyHzEditField.Value = 200;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'x(n)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Savetosignal1Button = uibutton(app.GridLayout4, 'push');
            app.Savetosignal1Button.ButtonPushedFcn = createCallbackFcn(app, @Savetosignal1ButtonPushed, true);
            app.Savetosignal1Button.Text = 'Save to signal 1';
            app.Savetosignal2Button = uibutton(app.GridLayout4, 'push');
            app.Savetosignal2Button.ButtonPushedFcn = createCallbackFcn(app, @Savetosignal2ButtonPushed, true);
            app.Savetosignal2Button.Text = 'Save to signal 2';
            app.CloseButton = uibutton(app.GridLayout4, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.CreateSyntheticSignalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateSyntheticSignalApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateSyntheticSignalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateSyntheticSignalUIFigure)
        end
    end
end