classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalProcessorUIFigure  matlab.ui.Figure
        AddsignalMenu            matlab.ui.container.Menu
        CreateMenu               matlab.ui.container.Menu
        SyntheticMenu            matlab.ui.container.Menu
        SineMenu                 matlab.ui.container.Menu
        CosineMenu               matlab.ui.container.Menu
        ChirpMenu                matlab.ui.container.Menu
        StepMenu                 matlab.ui.container.Menu
        RampMenu                 matlab.ui.container.Menu
        SawtoothMenu             matlab.ui.container.Menu
        RecordaudioMenu          matlab.ui.container.Menu
        AudiofileMenu            matlab.ui.container.Menu
        CaptureMenu              matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        Signal1Menu              matlab.ui.container.Menu
        ChannelMenu              matlab.ui.container.Menu
        OutMenu                  matlab.ui.container.Menu
        Result1Menu              matlab.ui.container.Menu
        AllMenu                  matlab.ui.container.Menu
        Signal2Menu              matlab.ui.container.Menu
        ChannelMenu_2            matlab.ui.container.Menu
        OutMenu_2                matlab.ui.container.Menu
        Result2Menu              matlab.ui.container.Menu
        AllMenu_2                matlab.ui.container.Menu
        StandardizationMenu      matlab.ui.container.Menu
        ViewMenu                 matlab.ui.container.Menu
        FrecuencyMenu            matlab.ui.container.Menu
        Signal1Menu_2            matlab.ui.container.Menu
        Signal2Menu_2            matlab.ui.container.Menu
        ResultsMenu              matlab.ui.container.Menu
        FiltersMenu              matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        TabGroup_2               matlab.ui.container.TabGroup
        Channel1Tab_2            matlab.ui.container.Tab
        GridLayout8_5            matlab.ui.container.GridLayout
        UIAxes3_5                matlab.ui.control.UIAxes
        Channel2Tab_2            matlab.ui.container.Tab
        GridLayout8_6            matlab.ui.container.GridLayout
        UIAxes3_6                matlab.ui.control.UIAxes
        Out1Tab_2                matlab.ui.container.Tab
        GridLayout8_7            matlab.ui.container.GridLayout
        UIAxes3_7                matlab.ui.control.UIAxes
        Out2Tab_2                matlab.ui.container.Tab
        GridLayout8_8            matlab.ui.container.GridLayout
        UIAxes3_8                matlab.ui.control.UIAxes
        Result2Tab               matlab.ui.container.Tab
        GridLayout10             matlab.ui.container.GridLayout
        UIAxes3_10               matlab.ui.control.UIAxes
        Panel_3                  matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        RestoreButton            matlab.ui.control.Button
        SetVolumeEditField       matlab.ui.control.NumericEditField
        SetVolumeEditFieldLabel  matlab.ui.control.Label
        StopsoundButton          matlab.ui.control.Button
        Signal2CheckBox          matlab.ui.control.CheckBox
        Signal1CheckBox          matlab.ui.control.CheckBox
        ApplyButton_3            matlab.ui.control.Button
        Switch                   matlab.ui.control.Switch
        Panel_4                  matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        Panel_7                  matlab.ui.container.Panel
        GridLayout12             matlab.ui.container.GridLayout
        MONOLabel_2              matlab.ui.control.Label
        ShitfButton2             matlab.ui.control.Button
        PlaychannelButton_2      matlab.ui.control.Button
        AddfilterPanel_2         matlab.ui.container.Panel
        GridLayout6_2            matlab.ui.container.GridLayout
        ApplyButton_2            matlab.ui.control.Button
        IIRButton_2              matlab.ui.control.Button
        FIRButton_2              matlab.ui.control.Button
        Panel_5                  matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        Knob                     matlab.ui.control.Knob
        Panel_2                  matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        Panel_6                  matlab.ui.container.Panel
        GridLayout11             matlab.ui.container.GridLayout
        MONOLabel                matlab.ui.control.Label
        ShiftButton              matlab.ui.control.Button
        PlaychannelButton        matlab.ui.control.Button
        AddfilterPanel           matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        ApplyButton              matlab.ui.control.Button
        IIRButton                matlab.ui.control.Button
        FIRButton                matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        ConverttomonoButton      matlab.ui.control.Button
        ConverttostereoButton    matlab.ui.control.Button
        Playbacksignal2Button    matlab.ui.control.Button
        Playbacksignal1Button    matlab.ui.control.Button
        MultiplyButton           matlab.ui.control.Button
        AddButton                matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        Channel1Tab              matlab.ui.container.Tab
        GridLayout8              matlab.ui.container.GridLayout
        UIAxes3                  matlab.ui.control.UIAxes
        Channel2Tab              matlab.ui.container.Tab
        GridLayout8_2            matlab.ui.container.GridLayout
        UIAxes3_2                matlab.ui.control.UIAxes
        Out1Tab                  matlab.ui.container.Tab
        GridLayout8_3            matlab.ui.container.GridLayout
        UIAxes3_3                matlab.ui.control.UIAxes
        Out2Tab                  matlab.ui.container.Tab
        GridLayout8_4            matlab.ui.container.GridLayout
        UIAxes3_4                matlab.ui.control.UIAxes
        Result1Tab               matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        UIAxes3_9                matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SyntheticSignalApp                      % Aplicacion señal sintetica
        OperationSig                            % Aplicacion operacion suma y multiplicacion
        MonoToStereo                            % Aplicacion mono a estereo
        StereoToMono                            % Aplicacion estereo a mono
        CreateSig                               % Aplicacion crear señal propia
        ViewFrec                                % Aplicacion para visualizar en el dominio de la frecuencia
        dataSignal1_1 = [];                     % Datos de la primera grafica canal 1
        dataSignal1_2 = [];                     % Datos de la primera grafica canal 2
        dataSignal1_3 = [];                     % Datos de la primera grafica out 1
        dataSignal1_4 = [];                     % Datos de la primera grafica out 2
        dataSignal2_1 = [];                     % Datos de la segunda grafica canal 1
        dataSignal2_2 = [];                     % Datos de la segunda grafica canal 2
        dataSignal2_3 = [];                     % Datos de la segunda grafica out 1
        dataSignal2_4 = [];                     % Datos de la segunda grafica out 2
        dataSignalResult1 = [];                 % Datos del resultado 1
        dataSignalResult2 = [];                 % Datos del resultado 2
        arraySamplingFrequencyGraph1 = [];      % Frecuencias de muestreo (canal 1, canal 2, out 1, out 2, result1)
        arraySamplingFrequencyGraph2 = [];      % Frecuencias de muestreo (canal 1, canal 2, out 1, out 2, result2)

        dataSignalHn1_1 = [];                   % Datos de la respuesta al impulso de la señal 1 de canal 1.
        dataSignalHn1_2 = [];                   % Datos de la respuesta al impulso de la señal 1 de canal 2.
        dataSignalHn2_1 = [];                   % Datos de la respuesta al impulso de la señal 2 de canal 1.
        dataSignalHn2_2 = [];                   % Datos de la respuesta al impulso de la señal 2 de canal 2.
        zero = [];                              % Posiciones iniciales de la respuesta al impulso.
        channelAux = [];                        % Guarda el canal de la señal a convolucionar.

        dataAuxYnTemp1 = [];                    % Datos temporales de la respuesta "y" obenida luego de aplicar la convolución o IIR señal 1.
        dataAuxYnTemp2 = [];                    % Datos temporales de la respuesta "y" obenida luego de aplicar la convolución o IIR señal 2.

        aux1ValueOperation = [];                % Varieable auxiliar que guarda el canal seleccionado 1.
        aux2ValueOperation = [];                % Varieable auxiliar que guarda el canal seleccionado 2.
        aux3ValueOperation = [];                % Varieable auxiliar que guarda el canal seleccionado.

        auxCloseConvolutionWindow1;             %Varaible para cerrar la ventana que se ha generado para la convolución o IIR.
        auxCloseConvolutionWindow2;             %Varaible para cerrar la ventana que se ha generado para la convolución o IIR.

        signalChannelButton1 = 1;               % Control ventana abierta señal 1
        signalChannelButton2 = 1;               % Control ventana abierta señal 2

        typeFilter1 = 0;                        % (Convolución por bloques = 0 / IIR = 1) para señal 1.
        typeFilter2 = 0;                        % (Convolución por bloques = 0 / IIR = 1) para señal 2.

        signalType1 = 'mono';                   % Indica si mi señal 1 es mono o estereo
        signalType2 = 'mono';                   % Indica si mi señal 2 es mono o estereo

        typeGanancia = 2;                       % Tipo de operación de aumento de volumen. 1 Lineal / 2 Exponencial.
        restaurar2 = [];                        % Restaurar señal a valores originales. 2
        cambio = [];                            % Canal modificado.
        restaurar1= [];                         % Restarar señal a valores orignales. 1

        dataSignalCoe1_1 = [];                   % Datos coeficientes de IIR de la señal 1 de canal 1.
        dataSignalCoe1_2 = [];                   % Datos coeficientes de IIR de la señal 1 de canal 2.
        dataSignalCoe2_1 = [];                   % Datos coeficientes de IIR de la señal 2 de canal 1.
        dataSignalCoe2_2 = [];                   % Datos coeficientes de IIR de la señal 2 de canal 2.

    end
    
    methods (Access = public)

        % Funcion encargada de graficar la señal 1 (instancia, n, x(n), canal, frecuencia de muestreo)
        function graphSignal1(app, dataN, dataX, number, sampFrec)
            % Verficamos que la señal no tengan valores no validos:
            dataX = elminateInfNan(app, dataX);
            % Dependiendo de number se guarda en el dataSignal respectivo y lo grafica (tambien su fs)
            switch number
                % Canal 1
                case 0
                    app.arraySamplingFrequencyGraph1 (1) = sampFrec;
                    app.dataSignal1_1 = [dataN; dataX];
                    stem(app.UIAxes3, app.dataSignal1_1(1,:), app.dataSignal1_1(2,:),".");
                % Canal 2
                case 1
                    app.arraySamplingFrequencyGraph1 (2) = sampFrec;
                    app.dataSignal1_2 = [dataN; dataX];
                    stem(app.UIAxes3_2, app.dataSignal1_2(1,:), app.dataSignal1_2(2,:),".");
                % Out 1
                case 3
                    app.arraySamplingFrequencyGraph1 (3) = sampFrec;
                    app.dataSignal1_3 = [dataN; dataX];
                    stem(app.UIAxes3_3, app.dataSignal1_3(1,:), app.dataSignal1_3(2,:),".");
                % Out 2
                case 4
                    app.arraySamplingFrequencyGraph1 (4) = sampFrec;
                    app.dataSignal1_4 = [dataN; dataX];
                    stem(app.UIAxes3_4, app.dataSignal1_4(1,:), app.dataSignal1_4(2,:),".");      
                % Result 1
                case 5
                    app.arraySamplingFrequencyGraph1 (5) = sampFrec;
                    app.dataSignalResult1 = [dataN; dataX];
                    stem(app.UIAxes3_9, app.dataSignalResult1(1,:), app.dataSignalResult1(2,:),"."); 
            end
        end
        % Funcion encargada de graficar la señal 2 (instancia, n, x(n), canal, frecuencia de muestreo)
        function graphSignal2(app, dataN, dataX, number, sampFrec)
            % Verficamos que la señal no tengan valores no validos:
            dataX = elminateInfNan(app, dataX);
            % Dependiendo de number se guarda en el dataSignal respectivo y lo grafica (tambien su fs)
            switch number
                % Canal 1
                case 0
                    app.arraySamplingFrequencyGraph2 (1) = sampFrec;
                    app.dataSignal2_1 = [dataN; dataX];
                    stem(app.UIAxes3_5, app.dataSignal2_1(1,:), app.dataSignal2_1(2,:),".");
                % Canal 2
                case 1
                    app.arraySamplingFrequencyGraph2 (2) = sampFrec;
                    app.dataSignal2_2 = [dataN; dataX];
                    stem(app.UIAxes3_6, app.dataSignal2_2(1,:), app.dataSignal2_2(2,:),".");
                % Out 1
                case 3
                    app.arraySamplingFrequencyGraph2 (3) = sampFrec;
                    app.dataSignal2_3 = [dataN; dataX];
                    stem(app.UIAxes3_7, app.dataSignal2_3(1,:), app.dataSignal2_3(2,:),".");
                % Out 2
                case 4
                    app.arraySamplingFrequencyGraph2 (4) = sampFrec;
                    app.dataSignal2_4 = [dataN; dataX];
                    stem(app.UIAxes3_8, app.dataSignal2_4(1,:), app.dataSignal2_4(2,:),".");
                % Result 2
                case 5
                    app.arraySamplingFrequencyGraph2 (5) = sampFrec;
                    app.dataSignalResult2 = [dataN; dataX];
                    stem(app.UIAxes3_10, app.dataSignalResult2(1,:), app.dataSignalResult2(2,:),"."); 
            end
        end
        % Funcion que cambia los colores de las pestaña de las graficas
        % dependiendo de si la señal 1 u 2 es mono o estereo
        function colorMonoStereo(app, graph, type)
            switch graph
                % Señal 1
                case 0
                    switch type
                        % Mono
                        case 'mono'
                            % Cambiar color señal 1
                            app.MONOLabel.Text = 'MONO';
                            app.GridLayout11.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_2.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_3.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_4.BackgroundColor = [0.94,0.76,0.76];
                        % Stereo
                        case 'stereo'    
                            % Cambiar color señal 1
                            app.MONOLabel.Text = 'STEREO';
                            app.GridLayout11.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_2.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_3.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_4.BackgroundColor = [0.94,0.76,0.76];
                    end 
                % Señal 2
                case 1
                    switch type
                        % Mono
                        case 'mono'
                            % Cambiar color señal 1
                            app.MONOLabel_2.Text = 'MONO';
                            app.GridLayout12.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_5.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_6.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_7.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_8.BackgroundColor = [0.94,0.76,0.76];
                        % Stereo
                        case 'stereo'    
                            % Cambiar color señal 1
                            app.MONOLabel_2.Text = 'STEREO';
                            app.GridLayout12.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_5.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_6.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_7.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_8.BackgroundColor = [0.94,0.76,0.76];
                   end  
            end
        end
        % Funcion que convierte a la señal 1 o 2 en estereo
        function monoStereo(app, signal, channel1, channel2)
            % Canal 1
            if channel1 == "Channel 1 S1"
                aux = app.dataSignal1_1;
                auxFs = app.arraySamplingFrequencyGraph1(1);
            elseif channel1 == "Channel 2 S1"
                aux = app.dataSignal1_2;
                auxFs = app.arraySamplingFrequencyGraph1(2);
            elseif channel1 == "Out 1 S1"
                aux = app.dataSignal1_3;
                auxFs = app.arraySamplingFrequencyGraph1(3);
            elseif channel1 == "Out 2 S1"
                aux = app.dataSignal1_4;
                auxFs = app.arraySamplingFrequencyGraph1(4);
            elseif channel1 == "Channel 1 S2"
                aux = app.dataSignal2_1;
                auxFs = app.arraySamplingFrequencyGraph2(1);
            elseif channel1 == "Channel 2 S2"
                aux = app.dataSignal2_2;
                auxFs = app.arraySamplingFrequencyGraph2(2);
            elseif channel1 == "Out 1 S2"
                aux = app.dataSignal2_3;
                auxFs = app.arraySamplingFrequencyGraph2(3);
            elseif channel1 == "Out 2 S2"
                aux = app.dataSignal2_4;
                auxFs = app.arraySamplingFrequencyGraph2(4);
            elseif channel1 == "Result 1"
                aux = app.dataSignalResult1;
                auxFs = app.arraySamplingFrequencyGraph1(5);
            elseif channel1 == "Result 2"
                aux = app.dataSignalResult2;
                auxFs = app.arraySamplingFrequencyGraph2(5);
            elseif channel1 == "Empty"
                aux = [zeros(1,2);zeros(1,2)];
                auxFs = 2;
            end
            % Canal 2
            if channel2 == "Channel 1 S1"
                aux2 = app.dataSignal1_1;
                auxFs2 = app.arraySamplingFrequencyGraph1(1);
            elseif channel2 == "Channel 2 S1"
                aux2 = app.dataSignal1_2;
                auxFs2 = app.arraySamplingFrequencyGraph1(2);
            elseif channel2 == "Out 1 S1"
                aux2 = app.dataSignal1_3;
                auxFs2 = app.arraySamplingFrequencyGraph1(3);
            elseif channel2 == "Out 2 S1"
                aux2 = app.dataSignal1_4;
                auxFs2 = app.arraySamplingFrequencyGraph1(4);
            elseif channel2 == "Channel 1 S2"
                aux2 = app.dataSignal2_1;
                auxFs2 = app.arraySamplingFrequencyGraph2(1);
            elseif channel2 == "Channel 2 S2"
                aux2 = app.dataSignal2_2;
                auxFs2 = app.arraySamplingFrequencyGraph2(2);
            elseif channel2 == "Out 1 S2"
                aux2 = app.dataSignal2_3;
                auxFs2 = app.arraySamplingFrequencyGraph2(3);
            elseif channel2 == "Out 2 S2"
                aux2 = app.dataSignal2_4;
                auxFs2 = app.arraySamplingFrequencyGraph2(4);
            elseif channel2 == "Result 1"
                aux2 = app.dataSignalResult1;
                auxFs2 = app.arraySamplingFrequencyGraph1(5);
            elseif channel2 == "Result 2"
                aux2 = app.dataSignalResult2;
                auxFs2 = app.arraySamplingFrequencyGraph2(5);
            elseif channel2 == "Empty"
                aux2 = [zeros(1,2);zeros(1,2)];
                auxFs2 = 2;
            end
            [aux, auxFs, aux2, auxFs2] = makePaddingFrecuency(app, aux, auxFs, aux2, auxFs2);
            aux3 = [aux; aux2];
            switch signal
                % Señal 1
                case 0
                    graphSignal1(app, aux3(1,:), aux3(2,:), 0, auxFs);
                    graphSignal1(app, aux3(size(aux,1) + 1,:), aux3(size(aux,1) + 2,:), 1, auxFs2);
                    app.signalType1 = 'stereo';
                    colorMonoStereo(app, 0, 'stereo')
                % Señal 2
                case 1
                    graphSignal2(app, aux3(1,:), aux3(2,:), 0, auxFs);
                    graphSignal2(app, aux3(size(aux,1) + 1,:), aux3(size(aux,1) + 2,:), 1, auxFs2);
                    app.signalType2 = 'stereo';
                    colorMonoStereo(app, 1, 'stereo')
            end
        end
        % Funcion que convierte a la señal 1 o 2 en mono
        function stereoMono(app, signal, saveTo, options)
            % Canal que se guardara
            if options == "Channel 1 S1"
                aux2 = app.dataSignal1_1;
                auxFs2 = app.arraySamplingFrequencyGraph1(1);
            elseif options == "Channel 2 S1"
                aux2 = app.dataSignal1_2;
                auxFs2 = app.arraySamplingFrequencyGraph1(2);
            elseif options == "Out 1 S1"
                aux2 = app.dataSignal1_3;
                auxFs2 = app.arraySamplingFrequencyGraph1(3);
            elseif options == "Out 2 S1"
                aux2 = app.dataSignal1_4;
                auxFs2 = app.arraySamplingFrequencyGraph1(4);
            elseif options == "Channel 1 S2"
                aux2 = app.dataSignal2_1;
                auxFs2 = app.arraySamplingFrequencyGraph2(1);
            elseif options == "Channel 2 S2"
                aux2 = app.dataSignal2_2;
                auxFs2 = app.arraySamplingFrequencyGraph2(2);
            elseif options == "Out 1 S2"
                aux2 = app.dataSignal2_3;
                auxFs2 = app.arraySamplingFrequencyGraph2(3);
            elseif options == "Out 2 S2"
                aux2 = app.dataSignal2_4;
                auxFs2 = app.arraySamplingFrequencyGraph2(4);
            elseif options == "Result 1"
                aux2 = app.dataSignalResult1;
                auxFs2 = app.arraySamplingFrequencyGraph1(5);
            elseif options == "Result 2"
                aux2 = app.dataSignalResult2;
                auxFs2 = app.arraySamplingFrequencyGraph2(5);
            elseif options == "Average Ch1 & Ch2"
                if signal == 0
                    signal1 = app.dataSignal1_1;
                    signal2 = app.dataSignal1_2;
                    fs1 = app.arraySamplingFrequencyGraph1(1);
                    fs2 = app.arraySamplingFrequencyGraph1(2);
                    [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                    aux2 = app.addFunction(signal1, signal2)/2;
                    auxFs2 = fs1;
                elseif signal == 1
                    signal1 = app.dataSignal2_1;
                    signal2 = app.dataSignal2_2;
                    fs1 = app.arraySamplingFrequencyGraph2(1);
                    fs2 = app.arraySamplingFrequencyGraph2(2);
                    [signal1, fs1, signal2, fs2] = app.makePaddingFrecuency(signal1, fs1, signal2, fs2);
                    aux2 = app.addFunction(signal1, signal2)/2;
                    auxFs2 = fs1;
                end 
                elseif options == "Average Out1 & Out2"
                if signal == 0
                    signal1 = app.dataSignal1_1;
                    signal2 = app.dataSignal1_2;
                    fs1 = app.arraySamplingFrequencyGraph1(1);
                    fs2 = app.arraySamplingFrequencyGraph1(2);
                    [signal1, fs1, signal2, fs2] = app.makePaddingFrecuency(signal1, fs1, signal2, fs2);
                    aux2 = app.addFunction(signal1, signal2)/2;
                    auxFs2 = fs1;
                elseif signal == 1
                    signal1 = app.dataSignal2_1;
                    signal2 = app.dataSignal2_2;
                    fs1 = app.arraySamplingFrequencyGraph2(1);
                    fs2 = app.arraySamplingFrequencyGraph2(2);
                    [signal1, fs1, signal2, fs2] = app.makePaddingFrecuency(signal1, fs1, signal2, fs2);
                    aux2 = app.addFunction(signal1, signal2)/2;
                    auxFs2 = fs1;
                end
            end
            switch signal
                % Señal 1
                case 0
                    % Canal a guardar
                    if saveTo == "Channel 1"
                        graphSignal1(app, signal1(1,:), aux2, 0, auxFs2);
                    elseif saveTo == "Channel 2"
                        graphSignal1(app, signal1(1,:), aux2, 1, auxFs2);
                    end
                    app.signalType1 = 'mono';
                    colorMonoStereo(app, 0, 'mono')
                % Señal 2
                case 1
                    % Canal a guardar
                    if saveTo == "Channel 1"
                        graphSignal2(app, signal2(1,:), aux2, 0, auxFs2);
                    elseif saveTo == "Channel 2"
                        graphSignal2(app, signal2(1,:), aux2, 1, auxFs2);
                    end
                    app.signalType2 = 'mono';
                    colorMonoStereo(app, 1, 'mono')
            end
        end
        
        % Función para guardar la respuesta al impulso seleccionada.
        function savehnConvolucion(app, hn, kn, chanel, zero, y, signal, proprietary)
            if(signal == 1)
                switch chanel
                    case 1
                        % Para cuando este en channel 1.
                        app.dataSignalHn1_1 = [hn; kn];
                        app.dataSignalCoe1_1 = [hn; kn];
                        app.zero(1) = zero;
                    case 2
                        % Para cuando este en channel 2.
                        app.dataSignalHn1_2 = [hn; kn];
                        app.dataSignalCoe1_2 = [hn; kn];
                        app.zero(2) = zero;
                    case 3
                        % Para cuando este en out 1.
                        app.dataSignalHn1_1 = [hn; kn];
                        app.dataSignalCoe1_1 = [hn; kn];
                        app.zero(1) = zero;
                    case 4
                        % Para cuando este en out 2.
                        app.dataSignalHn1_2 = [hn; kn];
                        app.dataSignalCoe1_2 = [hn; kn];
                        app.zero(2) = zero;
                    case 5
                        % Para cuando este en result 1.
                        app.dataSignalHn1_1 = [hn; kn];
                        app.dataSignalCoe1_1 = [hn; kn];
                        app.zero(1) = zero;
                end
                app.dataAuxYnTemp1 = y;
                app.channelAux(1) = chanel;
                if(app.typeFilter1 == 1)
                    delete(app.auxCloseConvolutionWindow1);
                end
                app.auxCloseConvolutionWindow1 = proprietary;
                app.typeFilter1 = 0;
            else
                switch chanel
                    case 1
                        % Para cuando este en channel 1.
                        app.dataSignalHn2_1 = [hn; kn];
                        app.dataSignalCoe2_1 = [hn; kn];
                        app.zero(3) = zero;
                    case 2
                        % Para cuando este en channel 2.
                        app.dataSignalHn2_2 = [hn; kn];
                        app.dataSignalCoe2_2 = [hn; kn];
                        app.zero(4) = zero;
                    case 3
                        % Para cuando este en out 1.
                        app.dataSignalHn2_1 = [hn; kn];
                        app.dataSignalCoe2_1 = [hn; kn];
                        app.zero(3) = zero;
                    case 4
                        % Para cuando este en out 2.
                        app.dataSignalHn2_2 = [hn; kn];
                        app.dataSignalCoe2_2 = [hn; kn];
                        app.zero(4) = zero;
                    case 5
                        % Para cuando este en result 1.
                        app.dataSignalHn2_1 = [hn; kn];
                        app.dataSignalCoe2_1 = [hn; kn];
                        app.zero(3) = zero;
                end
                app.dataAuxYnTemp2 = y;
                app.channelAux(2) = chanel;
                if(app.typeFilter2 == 1)
                    delete(app.auxCloseConvolutionWindow2);
                end
                app.auxCloseConvolutionWindow2 = proprietary;
                app.typeFilter2 = 0;
            end
        end

        % Función para graficar la respuesta al impulso:
        function graphHn(app, n, hn, title, signal)
            if(signal == 1)
                stem(app.UIAxes, n, hn);
                app.UIAxes.YGrid = 'on';
                app.UIAxes.XGrid = 'on';
                app.UIAxes.Title.String = string(title);
            else
                stem(app.UIAxes2, n, hn);
                app.UIAxes2.YGrid = 'on';
                app.UIAxes2.XGrid = 'on';
                app.UIAxes2.Title.String = string(title);
            end
        end
        
        % Función para eliminar valores no validos:
        function newSignal = elminateInfNan(app, signal)
            signal(isinf(signal) | isnan(signal)) = 0;
            newSignal = signal;
        end
        
        % Función para realizar padding y cambio de frecuencia:
        function [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2)
            if fs1 ~= fs2
                [signal1, fs1, signal2, fs2] = OperationSignals.changeFrecuency(signal1, fs1, signal2, fs2);
            end
            if~((signal1(1,1) == signal2(1,1)) && (signal1(1, end) == signal2(1, end)))
                [signal1, signal2]= OperationSignals.paddingDigital(signal1, signal2);
            end
        end

        %% Función para realizar la suma
        function results = addFunction(app, signal1, signal2)
            results = signal1(2, :) + signal2(2, :);
        end

        %% Función para almacenar el valor de la Equación:
        function saveEquation(app, channel, out, signal, calling, a, b)
            if(signal == 1)
                switch channel
                    case 1
                        % Para cuando este en channel 1.
                        app.dataSignalHn1_1 = [a; b];
                    case 2
                        % Para cuando este en channel 2.
                        app.dataSignalHn1_2 = [a; b];
                    case 3
                        % Para cuando este en out 1.
                        app.dataSignalHn1_1 = [a; b];
                    case 4
                        % Para cuando este en out 2.
                        app.dataSignalHn1_2 = [a; b];
                    case 5
                        % Para cuando este en result 1.
                        app.dataSignalHn1_1 = [a; b];
                end
                app.dataAuxYnTemp1 = out;
                app.channelAux(3) = channel;
                if(app.typeFilter1 == 0)
                    delete(app.auxCloseConvolutionWindow1);
                end
                app.auxCloseConvolutionWindow1 = calling;
                app.typeFilter1 = 1;
            else
                switch channel
                    case 1
                        % Para cuando este en channel 1.
                        app.dataSignalHn2_1 = [a; b];
                    case 2
                        % Para cuando estse en channel 2.
                        app.dataSignalHn2_2 = [a; b];
                    case 3
                        % Para cuando este en out 1.
                        app.dataSignalHn2_1 = [a; b];
                    case 4
                        % Para cuando este en out 2.
                        app.dataSignalHn2_2 = [a; b];
                    case 5
                        % Para cuando este en result 1.
                        app.dataSignalHn2_1 = [a; b];
                end
                app.dataAuxYnTemp2 = out;
                app.channelAux(4) = channel;
                if(app.typeFilter2 == 0)
                    delete(app.auxCloseConvolutionWindow2);
                end
                app.auxCloseConvolutionWindow2 = calling;
                app.typeFilter2 = 1;
            end
        end
        %% Funcion para realizar shift
        function desplazamiento_tiempo(app,n,x,indx,canal,signal)
            try
                ni_x = n(1);    %Instante inicial ni_x

                Lx=length(x); % Longitud de x(n)
                
                nf_x=Lx+ni_x-1; %Instante Final de x(n)
                
                nx=ni_x:nf_x;   %Instantes señal x(n)

                if canal <3
                    num = canal + 1;
                else
                    num = canal;
                end

               if signal == 1
                   switch  indx
    
                        case 1
                            if n(1) >= 0
                                y=fliplr(x);
                                ny= -nf_x:-ni_x;
                                app.graphSignal2( ny, y,canal, app.arraySamplingFrequencyGraph2(num));
                                switch num
                                    case 1
                                        app.dataSignal2_1 = [ny;y];
                                    case 2
                                        app.dataSignal2_2 = [ny;y];
                                    case 3
                                        app.dataSignal2_3 = [ny;y];
                                    case 4
                                        app.dataSignal2_4 = [ny;y];
                                    case 5
                                        app.dataSignalResult2 = [ny;y];
                                end
                            else 
                                msgbox('The signal must start in n >= 0', 'Error', 'error');
                            end
                           
                        case 2
                            dlg_title = 'Operaciones --> Variable Tiempo';
                            prompt = { 'Valor de retardo:'};
                            dims = [1 10]; 
                            defaultans = {'2'};
                            answer = inputdlg(prompt,dlg_title,dims,defaultans);
                            
                            k=abs(str2double(answer{1}));       % Obtención de k
                    
                            %Obtención y
                            y=x;
                            ny= (ni_x-k):(nf_x-k) ;
                            app.graphSignal2( ny, y,canal, app.arraySamplingFrequencyGraph2(num));
                            switch num
                                case 1
                                    app.dataSignal2_1 = [ny;y];
                                case 2
                                    app.dataSignal2_2 = [ny;y];
                                case 3
                                    app.dataSignal2_3 = [ny;y];
                                case 4
                                    app.dataSignal2_4 = [ny;y];
                                case 5
                                    app.dataSignalResult2 = [ny;y];
                            end
               
                        case 3
                            dlg_title = 'Operaciones --> Variable Tiempo';
                            prompt = {'Valor de adelanto:'};
                            dims = [1 10]; 
                            defaultans = {'2'};
                            answer = inputdlg(prompt,dlg_title,dims,defaultans);
                            
                            k=abs(str2double(answer{1}));       % Obtención de k
                    
                            %Obtención y
                            y=x;
                            ny= (ni_x+k):(nf_x+k) ;
                            app.graphSignal2( ny, y,canal, app.arraySamplingFrequencyGraph2(num));
                            switch num
                                case 1
                                    app.dataSignal2_1 = [ny;y];
                                case 2
                                    app.dataSignal2_2 = [ny;y];
                                case 3
                                    app.dataSignal2_3 = [ny;y];
                                case 4
                                    app.dataSignal2_4 = [ny;y];
                                case 5
                                    app.dataSignalResult2 = [ny;y];
                            end
                   end
               else
                   switch  indx
                       case 1
                            if n(1) >= 0
                                y=fliplr(x);
                                ny= -nf_x:-ni_x;
                                app.graphSignal1( ny, y,canal, app.arraySamplingFrequencyGraph1(num));
                                switch num
                                    case 1
                                        app.dataSignal1_1 = [ny;y];
                                    case 2
                                        app.dataSignal1_2 = [ny;y];
                                    case 3
                                        app.dataSignal1_3 = [ny;y];
                                    case 4
                                        app.dataSignal1_4 = [ny;y];
                                    case 5
                                        app.dataSignalResult1 = [ny;y];
                                end
                            else 
                                msgbox('The signal must start in n >= 0', 'Error', 'error');
                            end
                           
                        case 2
                            dlg_title = 'Operaciones --> Variable Tiempo';
                            prompt = { 'Valor de retardo:'};
                            dims = [1 10]; 
                            defaultans = {'2'};
                            answer = inputdlg(prompt,dlg_title,dims,defaultans);
                            
                            k=abs(str2double(answer{1}));       % Obtención de k
                    
                            %Obtención y
                            y=x;
                            ny= (ni_x-k):(nf_x-k) ;
                                app.graphSignal1( ny, y,canal, app.arraySamplingFrequencyGraph1(num));
                            switch num
                                case 1
                                    app.dataSignal1_1 = [ny;y];
                                case 2
                                    app.dataSignal1_2 = [ny;y];
                                case 3
                                    app.dataSignal1_3 = [ny;y];
                                case 4
                                    app.dataSignal1_4 = [ny;y];
                                case 5
                                    app.dataSignalResult1 = [ny;y];
                            end
                        case 3
                            dlg_title = 'Operaciones --> Variable Tiempo';
                            prompt = {'Valor de adelanto:'};
                            dims = [1 10]; 
                            defaultans = {'2'};
                            answer = inputdlg(prompt,dlg_title,dims,defaultans);
                            
                            k=abs(str2double(answer{1}));       % Obtención de k
                    
                            %Obtención y
                            y=x;
                            ny= (ni_x+k):(nf_x+k) ;
                            app.graphSignal1( ny, y,canal, app.arraySamplingFrequencyGraph1(num));
                            switch num
                                case 1
                                    app.dataSignal1_1 = [ny;y];
                                case 2
                                    app.dataSignal1_2 = [ny;y];
                                case 3
                                    app.dataSignal1_3 = [ny;y];
                                case 4
                                    app.dataSignal1_4 = [ny;y];
                                case 5
                                    app.dataSignalResult1 = [ny;y];
                            end
                   end
               end
       
            catch
            
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configura la posición de la figura para centrarla
           app.SignalProcessorUIFigure.WindowState = 'maximized';
        end

        % Menu selected function: SineMenu
        function SineMenuSelected(app, event)
            % Seno
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 0);
        end

        % Menu selected function: CosineMenu
        function CosineMenuSelected(app, event)
            % Coseno
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 1);
        end

        % Menu selected function: ChirpMenu
        function ChirpMenuSelected(app, event)
            % Chirp
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 2);
        end

        % Menu selected function: StepMenu
        function StepMenuSelected(app, event)
            % Escalon
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 3);
        end

        % Menu selected function: RampMenu
        function RampMenuSelected(app, event)
            % Rampa
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 4);
        end

        % Menu selected function: SawtoothMenu
        function SawtoothMenuSelected(app, event)
            % Diente de sierra
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 5);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Sumar señales
            app.OperationSig = OperationSignals(app, 0, app.signalChannelButton1, app.signalChannelButton2);
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            % Multiplicar señales
            app.OperationSig = OperationSignals(app, 1, app.signalChannelButton1, app.signalChannelButton2);
        end

        % Button down function: Channel1Tab
        function Channel1TabButtonDown(app, event)
            app.signalChannelButton1 = 1;
        end

        % Button down function: Channel2Tab
        function Channel2TabButtonDown(app, event)
            app.signalChannelButton1 = 2;
        end

        % Button down function: Out1Tab
        function Out1TabButtonDown(app, event)
            app.signalChannelButton1 = 3;
        end

        % Button down function: Out2Tab
        function Out2TabButtonDown(app, event)
            app.signalChannelButton1 = 4;
        end

        % Button down function: Result1Tab
        function Result1TabButtonDown(app, event)
            app.signalChannelButton1 = 5;
        end

        % Button down function: Channel1Tab_2
        function Channel1Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 1;
        end

        % Button down function: Channel2Tab_2
        function Channel2Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 2;
        end

        % Button down function: Out1Tab_2
        function Out1Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 3;
        end

        % Button down function: Out2Tab_2
        function Out2Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 4;
        end

        % Button down function: Result2Tab
        function Result2TabButtonDown(app, event)
            app.signalChannelButton2 = 5;
        end

        % Button pushed function: PlaychannelButton
        function PlaychannelButtonPushed(app, event)
            % Reproducir audio de la señal 1 dependiendo del canal
            % Solicitar fs e invertir
            dlg_title = 'Options';
            prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
            num_lines = 1;
            dims = [1 50];
            defaultans = {'44100', '0'};
            answer = inputdlg(prompt,dlg_title,dims,defaultans);
            if ~isempty(answer)
                Fs = str2num(answer{1});
                invertOption = str2num(answer{2});
                % Si la eleccion no es valida
                if invertOption ~= 0 && invertOption ~=1
                    errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                    invertOption = 0;
                else
                end
               % No invertir
                if invertOption == 0
                    switch app.signalChannelButton1
                        % Canal 1
                        case 1
                            sound(app.dataSignal1_1(2,:), Fs);
                        % Canal 2    
                        case 2
                            sound(app.dataSignal1_2(2,:), Fs);
                        % Out 1
                        case 3
                            sound(app.dataSignal1_3(2,:), Fs);
                        % Out 2
                        case 4
                            sound(app.dataSignal1_4(2,:), Fs);
                        % Result 1
                        case 5
                            sound(app.dataSignalResult1(2,:), Fs);
                    end
                % Invertir 
                elseif invertOption == 1
                    switch app.signalChannelButton1
                        % Canal 1
                        case 1
                            sound(fliplr(app.dataSignal1_1(2,:)), Fs);
                        % Canal 2
                        case 2
                            sound(fliplr(app.dataSignal1_2(2,:)), Fs);
                        % Out 1
                        case 3
                            sound(fliplr(app.dataSignal1_3(2,:)), Fs);
                        % Out 2
                        case 4
                            sound(fliplr(app.dataSignal1_4(2,:)), Fs);
                        % Result 2
                        case 5
                            sound(fliplr(app.dataSignalResult1(2,:)), Fs);
                    end
                end 
            else
            end
        end

        % Button pushed function: PlaychannelButton_2
        function PlaychannelButton_2Pushed(app, event)
            % Reproducir audio de la señal 2 dependiendo del canal
            % Solicitar fs e invertir
            dlg_title = 'Options';
            prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
            num_lines = 1;
            dims = [1 50];
            defaultans = {'44100', '0'};
            answer = inputdlg(prompt,dlg_title,dims,defaultans);
            if ~isempty(answer)
                Fs = str2num(answer{1});
                invertOption = str2num(answer{2});
                % Si la eleccion no es valida
                if invertOption ~= 0 && invertOption ~=1
                    errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                    invertOption = 0;
                else
                end
                % No invertir
                if invertOption == 0
                    switch app.signalChannelButton2
                        % Canal 1
                        case 1
                            sound(app.dataSignal2_1(2,:), Fs);
                        % Canal 2
                        case 2
                            sound(app.dataSignal2_2(2,:), Fs);
                        % Out 1
                        case 3
                            sound(app.dataSignal2_3(2,:), Fs);
                        % Out 2
                        case 4
                            sound(app.dataSignal2_4(2,:), Fs);
                        % Result 1
                        case 5
                            sound(app.dataSignalResult2(2,:), Fs);
                    end
                % Invertir
                elseif invertOption == 1
                    switch app.signalChannelButton2
                        % Canal 1
                        case 1
                            sound(fliplr(app.dataSignal2_1(2,:)), Fs);
                        % Canal 2
                        case 2
                            sound(fliplr(app.dataSignal2_2(2,:)), Fs);
                        % Out 1
                        case 3
                            sound(fliplr(app.dataSignal2_3(2,:)), Fs);
                        % Out 2
                        case 4
                            sound(fliplr(app.dataSignal2_4(2,:)), Fs);
                        % Result 2
                        case 5
                            sound(fliplr(app.dataSignalResult2(2,:)), Fs);
                    end
                end 
            else
            end
        end

        % Menu selected function: AudiofileMenu
        function AudiofileMenuSelected(app, event)
            % Solicitar seleccionar audio
            [filename, pathname] = uigetfile({'*.wav;*.mp3;*.ogg;*.flac;*.aac;*.m4a', 'Audio Files (*.wav, *.mp3, *.ogg, *.flac, *.aac, *.m4a)'; '*.*', 'All Files (*.*)'}, 'Select an audio file');
            if ~(isequal(filename, 0) || isequal(pathname, 0))
                [x,Fs] = audioread(fullfile(pathname,filename));
                n = 0:length(x)-1;
                % Solicitar seleccionar una señal
                options = {'Signal 1', 'Signal 2'};
                selection = listdlg('ListString', options, 'SelectionMode', 'single', 'PromptString', 'Select a signal to save:');
                if ~isempty(selection)
                    switch options{selection}
                        % Guarda en el canal 1 de la señal 1
                        case 'Signal 1'
                            x = x.';
                            % Si es estereo
                            if size(x, 1) == 2
                                app.signalType1 = 'stereo';
                                colorMonoStereo(app, 0, app.signalType1);
                                graphSignal1(app, n, x(1,:), 0, Fs);
                                graphSignal1(app, n, x(2,:), 1, Fs);
                            else
                                % Sino considerarla mono
                                app.signalType1 = 'mono';
                                colorMonoStereo(app, 0, app.signalType1);
                                % Solicitar seleccionar un canal
                                options2 = {'Channel 1','Channel 2'};
                                selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                                if ~isempty(selection2)
                                    switch options2{selection2}
                                        case 'Channel 1'
                                            graphSignal1(app, n, x(1,:), 0, Fs);
                                        case 'Channel 2'
                                            graphSignal1(app, n, x(1,:), 1, Fs);
                                    end
                                end
                            end
                            % Guarda en el canal 1 de la señal 2
                        case 'Signal 2'
                            x = x.';
                            % Si es estereo
                            if size(x, 1) == 2
                                app.signalType2 = 'stereo';
                                colorMonoStereo(app, 1, app.signalType2);
                                graphSignal2(app, n, x(1,:), 0, Fs);
                                graphSignal2(app, n, x(2,:), 1, Fs);
                            else
                                % Sino considerarla mono
                                app.signalType2 = 'mono';
                                colorMonoStereo(app, 1, app.signalType2);
                                % Solicitar seleccionar un canal
                                options2 = {'Channel 1','Channel 2'};
                                selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                                if ~isempty(selection2)
                                    switch options2{selection2}
                                        case 'Channel 1'
                                            graphSignal2(app, n, x(1,:), 0, Fs);
                                        case 'Channel 2'
                                            graphSignal2(app, n, x(1,:), 1, Fs);
                                    end
                                end
                            end
                    end
                else
                end
            else
            end
        end

        % Menu selected function: RecordaudioMenu
        function RecordaudioMenuSelected(app, event)
            % Solicitar parametros de grabacion
            dlg_title = 'Record audio';
            prompt = { 'Recording time (s)','Bits/samples', 'Sampling Frequency (Hz)','Number of channels (1,2)'};
            num_lines = 1;
            defaultans = {'7', '16','44100', '1'};
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            if ~isempty(answer)
                tRec=str2num(answer{1});    % Tiempo de grabación en s
                nBit=str2num(answer{2});   % Número de bits por muestra
                Fs=str2num(answer{3});    % Frecuencia de muestreo  
                nChan=str2num(answer{4});   % Número de canales
                % Si la eleccion de nChan no es valida
                if nChan ~= 1 && nChan ~=2
                    errordlg('The value of "Number of channels" must be 1 or 2. By default, 0 has been selected.', 'Error', 'modal');
                    nChan = 1;
                else
                end
                % Crear un objeto audiorecorder
                aud1 = audiorecorder(Fs,nBit,nChan);
                f = waitbar(0,'Recording');
                recordblocking(aud1, tRec); % Grabar  audio
                waitbar(1,f,'Recording');
                close(f);
                x = getaudiodata(aud1); % Obtener los datos de la señal x
                n = 0:length(x)-1;
                % Guardar
                options = {'Signal 1', 'Signal 2'};
                selection = listdlg('ListString', options, 'SelectionMode', 'single', 'PromptString', 'Select a signal to save:');
                if ~isempty(selection)
                    switch options{selection}
                        % Guarda en el canal 1 de la señal 1
                        case 'Signal 1'
                            x = x.';
                            % Si es estereo
                            if size(x, 1) == 2
                                app.signalType1 = 'stereo';
                                colorMonoStereo(app, 0, app.signalType1);
                                graphSignal1(app, n, x(1,:), 0, Fs);
                                graphSignal1(app, n, x(2,:), 1, Fs);
                            else
                                % Sino considerarla mono
                                app.signalType1 = 'mono';
                                colorMonoStereo(app, 0, app.signalType1);
                                % Solicitar seleccionar un canal
                                options2 = {'Channel 1','Channel 2'};
                                selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                                if ~isempty(selection2)
                                    switch options2{selection2}
                                        case 'Channel 1'
                                            graphSignal1(app, n, x(1,:), 0, Fs);
                                        case 'Channel 2'
                                            graphSignal1(app, n, x(1,:), 1, Fs);
                                    end
                                end
                            end
                        % Guarda en el canal 1 de la señal 2
                        case 'Signal 2'
                            x = x.';
                            % Si es estereo
                            if size(x, 1) == 2
                                app.signalType2 = 'stereo';
                                colorMonoStereo(app, 1, app.signalType2);
                                graphSignal2(app, n, x(1,:), 0, Fs);
                                graphSignal2(app, n, x(2,:), 1, Fs);
                            else
                                % Sino considerarla mono
                                app.signalType2 = 'mono';
                                colorMonoStereo(app, 1, app.signalType2);
                                % Solicitar seleccionar un canal
                                options2 = {'Channel 1','Channel 2'};
                                selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                                if ~isempty(selection2)
                                    switch options2{selection2}
                                        case 'Channel 1'
                                            graphSignal2(app, n, x(1,:), 0, Fs);
                                        case 'Channel 2'
                                            graphSignal2(app, n, x(1,:), 1, Fs);
                                    end
                                end
                            end
                    end
                else
                end
            else
            end
        end

        % Button pushed function: Playbacksignal1Button
        function Playbacksignal1ButtonPushed(app, event)
            % Verificar si la señal es estereo
            if app.signalType1 == "stereo"
                % Reproducir audio de la señal 1
                dlg_title = 'Options';
                prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
                num_lines = 1;
                dims = [1 50];
                defaultans = {'44100', '0'};  
                answer = inputdlg(prompt,dlg_title,dims,defaultans);
                if ~isempty(answer)
                    Fs = str2num(answer{1});
                    invertOption = str2num(answer{2});
                    % Si la eleccion no es valida
                    if invertOption ~= 0 && invertOption ~=1
                        errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                        invertOption = 0;
                    else
                    end
                    % No invertir
                    if invertOption == 0
                        switch app.signalChannelButton1
                            % Si esta ubicado en el canal 1 0 2 de la señal 1
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_1;
                                signal2 = app.dataSignal1_2;
                                fs1 = app.arraySamplingFrequencyGraph1(1);
                                fs2 = app.arraySamplingFrequencyGraph1(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 1
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_3;
                                signal2 = app.dataSignal1_4;
                                fs1 = app.arraySamplingFrequencyGraph1(3);
                                fs2 = app.arraySamplingFrequencyGraph1(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                        end
                    % Invertir
                    elseif invertOption == 1
                        switch app.signalChannelButton1
                            % Si esta ubicado en el canal 1 0 2 de la señal 1
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_1;
                                signal2 = app.dataSignal1_2;
                                fs1 = app.arraySamplingFrequencyGraph1(1);
                                fs2 = app.arraySamplingFrequencyGraph1(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 1
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_3;
                                signal2 = app.dataSignal1_4;
                                fs1 = app.arraySamplingFrequencyGraph1(3);
                                fs2 = app.arraySamplingFrequencyGraph1(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                        end
                    end
                else
                end
            % Sino es estereo
            else
                errordlg('The signal is not stereo, please try again when it is.', 'Error', 'modal');
            end
        end

        % Button pushed function: Playbacksignal2Button
        function Playbacksignal2ButtonPushed(app, event)
            % Verificar si la señal es estereo
            if app.signalType2 == "stereo"
                % Reproducir audio de la señal 2
                dlg_title = 'Options';
                prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
                num_lines = 1;
                dims = [1 50];
                defaultans = {'44100', '0'};  
                answer = inputdlg(prompt,dlg_title,dims,defaultans);
                if ~isempty(answer)
                    Fs = str2num(answer{1});
                    invertOption = str2num(answer{2});
                    % Si la eleccion no es valida
                    if invertOption ~= 0 && invertOption ~=1
                        errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                        invertOption = 0;
                    else
                    end
                    % No invertir
                    if invertOption == 0
                        switch app.signalChannelButton2
                            % Si esta ubicado en el canal 1 0 2 de la señal 2
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_1;
                                signal2 = app.dataSignal2_2;
                                fs1 = app.arraySamplingFrequencyGraph2(1);
                                fs2 = app.arraySamplingFrequencyGraph2(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 2
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_3;
                                signal2 = app.dataSignal2_4;
                                fs1 = app.arraySamplingFrequencyGraph2(3);
                                fs2 = app.arraySamplingFrequencyGraph2(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                        end
                    % Invertir
                    elseif invertOption == 1
                        switch app.signalChannelButton2
                            % Si esta ubicado en el canal 1 0 2 de la señal 2
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_1;
                                signal2 = app.dataSignal2_2;
                                fs1 = app.arraySamplingFrequencyGraph2(1);
                                fs2 = app.arraySamplingFrequencyGraph2(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 2
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_3;
                                signal2 = app.dataSignal2_4;
                                fs1 = app.arraySamplingFrequencyGraph2(3);
                                fs2 = app.arraySamplingFrequencyGraph2(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                        end
                    end
                else
                end
            % Sino es estereo
            else
                errordlg('The signal is not stereo, please try again when it is.', 'Error', 'modal');
            end
        end

        % Button pushed function: ConverttostereoButton
        function ConverttostereoButtonPushed(app, event)
            select = questdlg('Select the signal to change', ' Select signal', ...
                'Signal 1', 'Signal 2', 'Cancel', ...
                'Signal 1');
            switch select
                case 'Signal 1'
                    app.MonoToStereo = ConvertMonoToStereo(app, 0);
                case 'Signal 2'
                    app.MonoToStereo = ConvertMonoToStereo(app, 1);
                case 'Cancel'
            end
        end

        % Button pushed function: ConverttomonoButton
        function ConverttomonoButtonPushed(app, event)
            select = questdlg('Select the signal to change', ' Select signal', ...
                'Signal 1', 'Signal 2', 'Cancel', ...
                'Signal 1');
            switch select
                case 'Signal 1'
                    app.StereoToMono = ConvertStereoToMono(app, 0);
                case 'Signal 2'
                    app.StereoToMono = ConvertStereoToMono(app, 1);
                case 'Cancel'
            end
        end

        % Button pushed function: FIRButton
        function FIRButtonPushed(app, event)
            switch app.signalChannelButton1
                case 1
                    if(~isempty(app.dataSignal1_1))
                        if(isempty(app.dataSignalCoe1_1))
                            convolutionBlocks(app, app.dataSignal1_1, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_1, app.signalChannelButton1, app.dataSignalCoe1_1, app.zero(1), 1);
                        end
                    else
                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error');
                        return;
                    end
                case 2
                    if(~isempty(app.dataSignal1_2))
                        if(isempty(app.dataSignalCoe1_2))
                            convolutionBlocks(app, app.dataSignal1_2, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_2, app.signalChannelButton1, app.dataSignalCoe1_2, app.zero(2), 1);
                        end
                    else
                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                        return;
                    end
                case 3
                    if(~isempty(app.dataSignal1_3))
                        if(isempty(app.dataSignalCoe1_1))
                            convolutionBlocks(app, app.dataSignal1_3, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_3, app.signalChannelButton1, app.dataSignalCoe1_1, app.zero(1), 1);
                        end
                    else
                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                        return;
                    end
                case 4
                    if(~isempty(app.dataSignal1_4))
                        if(isempty(app.dataSignalCoe1_2))
                            convolutionBlocks(app, app.dataSignal1_4, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_4, app.signalChannelButton1, app.dataSignalCoe1_2, app.zero(2), 1);
                        end
                    else
                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                        return;
                    end
                case 5
                    if(~isempty(app.dataSignalResult1))
                        if(isempty(app.dataSignalCoe1_1))
                            convolutionBlocks(app, app.dataSignalResult1, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignalResult1, app.signalChannelButton1, app.dataSignalCoe1_1, app.zero(1), 1);
                        end
                    else
                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                        return;
                    end  
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
             if(~isempty(app.dataAuxYnTemp1))
                if(app.typeFilter1 == 0)
                    %Convolución:
                    if(mod(app.channelAux(1), 2) == 0)
                        graphHn(app, app.dataSignalHn1_2(2,:), app.dataSignalHn1_2(1,:), 'h1(n) by Convolution.', 1);
                        graphSignal1(app, app.dataAuxYnTemp1(2,:), app.dataAuxYnTemp1(1,:), 4, app.arraySamplingFrequencyGraph1(2));
                    else
                        graphHn(app, app.dataSignalHn1_1(2,:), app.dataSignalHn1_1(1,:), 'h1(n) by Convolution.', 1);
                        graphSignal1(app, app.dataAuxYnTemp1(2,:), app.dataAuxYnTemp1(1,:), 3, app.arraySamplingFrequencyGraph1(1));
                    end
                    delete(app.auxCloseConvolutionWindow1);
                    app.dataAuxYnTemp1 = [];
                    app.channelAux(1) = 0;
                else
                    %Ecuaciones de diferencia:
                    if(mod(app.channelAux(3), 2) == 0)
                        h = impz(app.dataSignalHn1_2(1,:), app.dataSignalHn1_2(2,:));
                        stem(app.UIAxes,0:length(h)-1, h);
                        app.UIAxes.Title.String = 'h1(n) by Equation.';
                        graphSignal1(app, app.dataAuxYnTemp1(2,:), app.dataAuxYnTemp1(1,:), 4, app.arraySamplingFrequencyGraph1(2));
                    else
                        h = impz(app.dataSignalHn1_1(1,:), app.dataSignalHn1_1(2,:));
                        stem(app.UIAxes,0:length(h)-1, h);
                        app.UIAxes.Title.String = 'h1(n) by Equation.';
                        graphSignal1(app, app.dataAuxYnTemp1(2,:), app.dataAuxYnTemp1(1,:), 3, app.arraySamplingFrequencyGraph1(1));
                    end
                    delete(app.auxCloseConvolutionWindow1);
                    app.dataAuxYnTemp1 = [];
                    app.channelAux(3) = 0;
                end
            else
                msgbox('No filtering process has been carried out', 'Error', 'error');
                return;
            end
        end

        % Button pushed function: FIRButton_2
        function FIRButton_2Pushed(app, event)
            switch app.signalChannelButton2
                case 1
                    if(~isempty(app.dataSignal2_1))
                        if(isempty(app.dataSignalCoe2_1))
                            convolutionBlocks(app, app.dataSignal2_1, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_1, app.signalChannelButton2, app.dataSignalCoe2_1, app.zero(3), 2);
                        end
                    else
                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error');
                        return;
                    end
                case 2
                    if(~isempty(app.dataSignal2_2))
                        if(isempty(app.dataSignalCoe2_2))
                            convolutionBlocks(app, app.dataSignal2_2, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_2, app.signalChannelButton2, app.dataSignalCoe2_2, app.zero(4), 2);
                        end
                    else
                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                        return;
                    end
                case 3
                    if(~isempty(app.dataSignal2_3))
                        if(isempty(app.dataSignalCoe2_1))
                            convolutionBlocks(app, app.dataSignal2_3, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_3, app.signalChannelButton2, app.dataSignalCoe2_1, app.zero(3), 2);
                        end
                    else
                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                        return;
                    end
                case 4
                    if(~isempty(app.dataSignal2_4))
                        if(isempty(app.dataSignalCoe2_2))
                            convolutionBlocks(app, app.dataSignal2_4, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_4, app.signalChannelButton2, app.dataSignalCoe2_2, app.zero(4), 2);
                        end
                    else
                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                        return;
                    end
                case 5
                    if(~isempty(app.dataSignalResult2))
                        if(isempty(app.dataSignalCoe2_1))
                            convolutionBlocks(app, app.dataSignalResult2, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignalResult2, app.signalChannelButton2, app.dataSignalCoe2_1, app.zero(3), 2);
                        end
                    else
                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                        return;
                    end  
            end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            if(~isempty(app.dataAuxYnTemp2))
                if(app.typeFilter2 == 0)
                    % Convolución:
                    if(mod(app.channelAux(2), 2) == 0)
                        graphHn(app, app.dataSignalHn2_2(2,:), app.dataSignalHn2_2(1,:), 'h2(n) by Convolution.', 2);
                        graphSignal2(app, app.dataAuxYnTemp2(2,:), app.dataAuxYnTemp2(1,:), 4, app.arraySamplingFrequencyGraph2(2));
                    else
                        graphHn(app, app.dataSignalHn2_1(2,:), app.dataSignalHn2_1(1,:), 'h2(n) by Convolution.', 2);
                        graphSignal2(app, app.dataAuxYnTemp2(2,:), app.dataAuxYnTemp2(1,:), 3, app.arraySamplingFrequencyGraph2(1));
                    end
                    delete(app.auxCloseConvolutionWindow2);
                    app.dataAuxYnTemp2 = [];
                    app.channelAux(2) = 0;
                    delete(app.auxCloseConvolutionWindow2);
                else
                    %Ecuaciones de diferencias:
                    if(mod(app.channelAux(4), 2) == 0)
                        h = impz(app.dataSignalHn2_2(1,:), app.dataSignalHn2_2(2,:));
                        stem(app.UIAxes2,0:length(h)-1, h);
                        app.UIAxes2.Title.String = 'h2(n) by Equation.';
                        graphSignal2(app, app.dataAuxYnTemp2(2,:), app.dataAuxYnTemp2(1,:), 4, app.arraySamplingFrequencyGraph2(2));
                    else
                        h = impz(app.dataSignalHn2_1(1,:), app.dataSignalHn2_1(2,:));
                        stem(app.UIAxes2,0:length(h)-1, h);
                        app.UIAxes2.Title.String = 'h2(n) by Equation.';
                        graphSignal2(app, app.dataAuxYnTemp2(2,:), app.dataAuxYnTemp2(1,:), 3, app.arraySamplingFrequencyGraph2(1));
                    end
                    delete(app.auxCloseConvolutionWindow2);
                    app.dataAuxYnTemp2 = [];
                    app.channelAux(4) = 0;
                    delete(app.auxCloseConvolutionWindow2);
                end
            else
                msgbox('No filtering process has been carried out', 'Error', 'error');
                return;
            end
        end

        % Menu selected function: StandardizationMenu
        function StandardizationMenuSelected(app, event)
            normalizacion(app, app.signalChannelButton1, app.signalChannelButton2);
        end

        % Menu selected function: CreateMenu
        function CreateMenuSelected(app, event)
            app.CreateSig = CreateSignal(app);
        end

        % Menu selected function: Signal1Menu_2
        function Signal1Menu_2Selected(app, event)
            app.ViewFrec = ViewFrecuency(app, 1, app.dataSignal1_1, app.dataSignal1_2, ...
                app.dataSignal1_3, app.dataSignal1_4, app.arraySamplingFrequencyGraph1);
        end

        % Menu selected function: Signal2Menu_2
        function Signal2Menu_2Selected(app, event)
            app.ViewFrec = ViewFrecuency(app, 2, app.dataSignal2_1, app.dataSignal2_2, ...
                app.dataSignal2_3, app.dataSignal2_4, app.arraySamplingFrequencyGraph2);
        end

        % Menu selected function: ResultsMenu
        function ResultsMenuSelected(app, event)
            frec = [app.arraySamplingFrequencyGraph1(5),app.arraySamplingFrequencyGraph2(5)];
            app.ViewFrec = ViewFrecuency(app, 3, app.dataSignalResult1, app.dataSignalResult2, ...
                NULL, NULL, frec);
        end

        % Menu selected function: FiltersMenu
        function FiltersMenuSelected(app, event)
            typeFilterView = [app.typeFilter1, app.typeFilter2];
            app.ViewFrec = ViewFrecuency(app, 4, app.dataSignalHn1_1, app.dataSignalHn1_2, ...
                app.dataSignalHn2_1, app.dataSignalHn2_2, typeFilterView);
        end

        % Button pushed function: StopsoundButton
        function StopsoundButtonPushed(app, event)
            clear sound;
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.SetVolumeEditField.Value = changingValue;
        end

        % Value changed function: SetVolumeEditField
        function SetVolumeEditFieldValueChanged(app, event)
            value = app.SetVolumeEditField.Value;
            if (value < 20 && value > -20)
                app.Knob.Value = value;
            else
                msgbox('Gain level outside accepted limits.', 'Error', 'error');
                app.SetVolumeEditField.Value = app.Knob.Value;
                return;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Linear'
                    app.typeGanancia = 1;
                case 'Constant'
                    app.typeGanancia = 2;
            end
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            switch app.typeGanancia
                case 1
                    if app.Signal1CheckBox.Value
                        switch app.signalChannelButton1
                            case 1
                                if ~isempty(app.dataSignal1_1)
                                    app.restaurar1 = app.dataSignal1_1(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal1_1(1,:)) - 1;
                                        app.dataSignal1_1(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignal1_1(2,:));
                                    else
                                        n = (length(app.dataSignal1_1(1,:)) - 1):-1:0;
                                        app.dataSignal1_1(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignal1_1(2,:));
                                    end
                                    graphSignal1(app, app.dataSignal1_1(1,:), app.dataSignal1_1(2,:), 0, app.arraySamplingFrequencyGraph1(1));
                                else
                                    msgbox('No signal on Channel 1', 'Error', 'error');
                                    return;
                                end
                            case 2
                                if ~isempty(app.dataSignal1_2)
                                    app.restaurar1 = app.dataSignal1_2(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal1_2(1,:)) - 1;
                                        app.dataSignal1_2(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignal1_2(2,:));
                                    else
                                        n = (length(app.dataSignal1_2(1,:)) - 1):-1:0;
                                        app.dataSignal1_2(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignal1_2(2,:));
                                    end
                                    graphSignal1(app, app.dataSignal1_2(1,:), app.dataSignal1_2(2,:), 1, app.arraySamplingFrequencyGraph1(2));
                                else
                                    msgbox('No signal on Channel 2', 'Error', 'error');
                                    return;
                                end
                            case 3
                                if ~isempty(app.dataSignal1_3) 
                                    app.restaurar1 = app.dataSignal1_3(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal1_3(1,:)) - 1;
                                        app.dataSignal1_3(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignal1_3(2,:));
                                    else
                                        n = (length(app.dataSignal1_3(1,:)) - 1):-1:0;
                                        app.dataSignal1_3(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignal1_3(2,:));
                                    end
                                    graphSignal1(app, app.dataSignal1_3(1,:), app.dataSignal1_3(2,:), 3, app.arraySamplingFrequencyGraph1(3));
                                else
                                    msgbox('No signal on Out 1', 'Error', 'error');
                                    return;
                                end
                            case 4
                                if ~isempty(app.dataSignal1_4)
                                    app.restaurar1 = app.dataSignal1_4(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal1_4(1,:)) - 1;
                                        app.dataSignal1_4(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignal1_4(2,:));
                                    else
                                        n = (length(app.dataSignal1_4(1,:)) - 1):-1:0;
                                        app.dataSignal1_4(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignal1_4(2,:));
                                    end
                                    graphSignal1(app, app.dataSignal1_4(1,:), app.dataSignal1_4(2,:), 4, app.arraySamplingFrequencyGraph1(4));
                                else
                                    msgbox('No signal on Out 2', 'Error', 'error');
                                    return;
                                end
                            case 5
                                if ~isempty(app.dataSignalResult1)
                                    app.restaurar1 = app.dataSignalResult1(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignalResult1(1,:)) - 1;
                                        app.dataSignalResult1(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignalResult1(2,:));
                                    else
                                        n = (length(app.dataSignalResult1(1,:)) - 1):-1:0;
                                        app.dataSignalResult1(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignalResult1(2,:));
                                    end
                                    graphSignal1(app, app.dataSignalResult1(1,:), app.dataSignalResult1(2,:), 5, app.arraySamplingFrequencyGraph1(5));
                                else
                                    msgbox('No signal on Result 1', 'Error', 'error');
                                    return;
                                end
                        end
                        app.cambio(1) = app.signalChannelButton1;
                    end
                    if app.Signal2CheckBox.Value
                        switch app.signalChannelButton2
                            case 1
                                if ~isempty(app.dataSignal2_1)
                                    app.restaurar2 = app.dataSignal2_1(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal2_1(1,:)) - 1;
                                        app.dataSignal2_1(2,:) = (((abs(app.SetVolumeEditField.Value) / n(end)) * n).*app.dataSignal2_1(2,:));
                                    else
                                        n = (length(app.dataSignal2_1(1,:)) - 1):-1:0;
                                        app.dataSignal2_1(2,:) = (((abs(app.SetVolumeEditField.Value) / n(1)) * n).*app.dataSignal2_1(2,:));
                                    end
                                    graphSignal2(app, app.dataSignal2_1(1,:), app.dataSignal2_1(2,:), 0, app.arraySamplingFrequencyGraph2(1));
                                else
                                    msgbox('No signal on Channel 1', 'Error', 'error');
                                    return;
                                end
                            case 2
                                if ~isempty(app.dataSignal2_2)
                                    app.restaurar2 = app.dataSignal2_2(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal2_2(1,:)) - 1;
                                        app.dataSignal2_2(2,:) = (((app.SetVolumeEditField.Value / n(end)) * n).*app.dataSignal2_2(2,:));
                                    else
                                        n = (length(app.dataSignal2_2(1,:)) - 1):-1:0;
                                        app.dataSignal2_2(2,:) = (((abs(app.SetVolumeEditField.Value) / n(1)) * n).*app.dataSignal2_2(2,:));
                                    end
                                    graphSignal2(app, app.dataSignal2_2(1,:), app.dataSignal2_2(2,:), 1, app.arraySamplingFrequencyGraph2(2));
                                else
                                    msgbox('No signal on Channel 2', 'Error', 'error');
                                    return;
                                end
                            case 3
                                if ~isempty(app.dataSignal2_3)
                                    app.restaurar2 = app.dataSignal2_3(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal2_3(1,:)) - 1;
                                        app.dataSignal2_3(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignal2_3(2,:));
                                    else
                                        n = (length(app.dataSignal2_3(1,:)) - 1):-1:0;
                                        app.dataSignal2_3(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignal2_3(2,:));
                                    end
                                    graphSignal2(app, app.dataSignal2_3(1,:), app.dataSignal2_3(2,:), 3, app.arraySamplingFrequencyGraph2(3));
                                else
                                    msgbox('No signal on Out 1', 'Error', 'error');
                                    return;
                                end
                            case 4
                                if ~isempty(app.dataSignal2_4)
                                    app.restaurar2 = app.dataSignal2_4(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignal2_4(1,:)) - 1;
                                        app.dataSignal2_4(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignal2_4(2,:));
                                    else
                                        n = (length(app.dataSignal2_4(1,:)) - 1):-1:0;
                                        app.dataSignal2_4(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignal2_4(2,:));
                                    end
                                    graphSignal2(app, app.dataSignal2_4(1,:), app.dataSignal2_4(2,:), 4, app.arraySamplingFrequencyGraph2(4));
                                else
                                    msgbox('No signal on Out 2', 'Error', 'error');
                                    return;
                                end
                            case 5
                                if ~isempty(app.dataSignalResult2)
                                    app.restaurar2 = app.dataSignalResult2(2,:);
                                    if (app.SetVolumeEditField.Value > 0)
                                        n = 0:1:length(app.dataSignalResult2(1,:)) - 1;
                                        app.dataSignalResult2(2,:) = (((app.SetVolumeEditField.Value/ n(end)) * n).*app.dataSignalResult2(2,:));
                                    else
                                        n = (length(app.dataSignalResult2(1,:)) - 1):-1:0;
                                        app.dataSignalResult2(2,:) = (((abs(app.SetVolumeEditField.Value)/ n(1)) * n).*app.dataSignalResult2(2,:));
                                    end
                                    graphSignal2(app, app.dataSignalResult2(1,:), app.dataSignalResult2(2,:), 5, app.arraySamplingFrequencyGraph2(5));
                                else
                                    msgbox('No signal on Result 2', 'Error', 'error');
                                    return;
                                end
                        end
                        app.cambio(2) = app.signalChannelButton2;
                    end
                case 2
                    if app.Signal1CheckBox.Value
                        switch app.signalChannelButton1
                            case 1
                                if ~isempty(app.dataSignal1_1)
                                    app.restaurar1 = app.dataSignal1_1(2,:);
                                    app.dataSignal1_1(2,:) = app.SetVolumeEditField.Value*app.dataSignal1_1(2,:);
                                    graphSignal1(app, app.dataSignal1_1(1,:), app.dataSignal1_1(2,:), 0, app.arraySamplingFrequencyGraph1(1));
                                else
                                    msgbox('No signal on Channel 1', 'Error', 'error');
                                    return;
                                end
                            case 2
                                if ~isempty(app.dataSignal1_2)
                                    app.restaurar1 = app.dataSignal1_2(2,:);
                                    app.dataSignal1_2(2,:) = app.SetVolumeEditField.Value*app.dataSignal1_2(2,:);
                                    graphSignal1(app, app.dataSignal1_2(1,:), app.dataSignal1_2(2,:), 1, app.arraySamplingFrequencyGraph1(2));
                                else
                                    msgbox('No signal on Channel 2', 'Error', 'error');
                                    return;
                                end
                            case 3
                                if ~isempty(app.dataSignal1_3)
                                    app.restaurar1 = app.dataSignal1_3(2,:);
                                    app.dataSignal1_3(2,:) = app.SetVolumeEditField.Value*app.dataSignal1_3(2,:);
                                    graphSignal1(app, app.dataSignal1_3(1,:), app.dataSignal1_3(2,:), 3, app.arraySamplingFrequencyGraph1(3));
                                else
                                    msgbox('No signal on Out 1', 'Error', 'error');
                                    return;
                                end
                            case 4
                                if ~isempty(app.dataSignal1_4)
                                    app.restaurar1 = app.dataSignal1_4(2,:);
                                    app.dataSignal1_4(2,:) = app.SetVolumeEditField.Value*app.dataSignal1_4(2,:);
                                    graphSignal1(app, app.dataSignal1_4(1,:), app.dataSignal1_4(2,:), 4, app.arraySamplingFrequencyGraph1(4));
                                else
                                    msgbox('No signal on Out 2', 'Error', 'error');
                                    return;
                                end
                            case 5
                                if ~isempty(app.dataSignalResult1)
                                    app.restaurar1 = app.dataSignalResult1(2,:);
                                    app.dataSignalResult1(2,:) = app.SetVolumeEditField.Value*app.dataSignalResult1(2,:);
                                    graphSignal1(app, app.dataSignalResult1(1,:), app.dataSignalResult1(2,:), 5, app.arraySamplingFrequencyGraph1(5));
                                else
                                    msgbox('No signal on Result 1', 'Error', 'error');
                                    return;
                                end
                        end
                        app.cambio(1) = app.signalChannelButton1;
                    end
                    if app.Signal2CheckBox.Value
                        switch app.signalChannelButton2
                            case 1
                                if ~isempty(app.dataSignal2_1)
                                    app.restaurar2 = app.dataSignal2_1(2,:);
                                    app.dataSignal2_1(2,:) = app.SetVolumeEditField.Value*app.dataSignal2_1(2,:);
                                    graphSignal2(app, app.dataSignal2_1(1,:), app.dataSignal2_1(2,:), 0, app.arraySamplingFrequencyGraph2(1));
                                    
                                else
                                    msgbox('No signal on Channel 1', 'Error', 'error');
                                    return;
                                end
                            case 2
                                if ~isempty(app.dataSignal2_2)
                                    app.restaurar2 = app.dataSignal2_2(2,:);
                                    app.dataSignal2_2(2,:) = app.SetVolumeEditField.Value*app.dataSignal2_2(2,:);
                                    graphSignal2(app, app.dataSignal2_2(1,:), app.dataSignal2_2(2,:), 1, app.arraySamplingFrequencyGraph2(2));
                                else
                                    msgbox('No signal on Channel 2', 'Error', 'error');
                                    return;
                                end
                            case 3
                                if ~isempty(app.dataSignal2_3)
                                    app.restaurar2 = app.dataSignal2_3(2,:);
                                    app.dataSignal2_3(2,:) = app.SetVolumeEditField.Value*app.dataSignal2_3(2,:);
                                    graphSignal2(app, app.dataSignal2_3(1,:), app.dataSignal2_3(2,:), 3, app.arraySamplingFrequencyGraph2(3));
                                else
                                    msgbox('No signal on Out 1', 'Error', 'error');
                                    return;
                                end
                            case 4
                                if ~isempty(app.dataSignal2_4)
                                    app.restaurar2 = app.dataSignal2_4(2,:);
                                    app.dataSignal2_4(2,:) = app.SetVolumeEditField.Value*app.dataSignal2_4(2,:);
                                    graphSignal2(app, app.dataSignal2_4(1,:), app.dataSignal2_4(2,:), 4, app.arraySamplingFrequencyGraph2(4));
                                else
                                    msgbox('No signal on Out 2', 'Error', 'error');
                                    return;
                                end
                            case 5
                                if ~isempty(app.dataSignalResult2)
                                    app.restaurar2 = app.dataSignalResult2(2,:);
                                    app.dataSignalResult2(2,:) = app.SetVolumeEditField.Value*app.dataSignalResult2(2,:);
                                    graphSignal2(app, app.dataSignalResult2(1,:), app.dataSignalResult2(2,:), 5, app.arraySamplingFrequencyGraph2(5));
                                else
                                    msgbox('No signal on Result 2', 'Error', 'error');
                                    return;
                                end
                        end
                        app.cambio(2) = app.signalChannelButton2;
                    end
            end
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            if ~isempty(app.restaurar1)
                switch app.cambio(1)
                    case 1
                        graphSignal1(app, app.dataSignal1_1(1,:), app.restaurar1, 0, app.arraySamplingFrequencyGraph1(1));
                    case 2
                        graphSignal1(app, app.dataSignal1_2(1,:), app.restaurar1, 1, app.arraySamplingFrequencyGraph1(2));
                    case 3
                        graphSignal1(app, app.dataSignal1_3(1,:), app.restaurar1, 3, app.arraySamplingFrequencyGraph1(3));
                    case 4
                        graphSignal1(app, app.dataSignal1_4(1,:), app.restaurar1, 4, app.arraySamplingFrequencyGraph1(4));
                    case 5
                        graphSignal1(app, app.dataSignalResult1(1,:), app.restaurar1, 5, app.arraySamplingFrequencyGraph1(5));
                end
            end
            if ~isempty(app.restaurar2)
                switch app.cambio(2)
                    case 1
                        graphSignal2(app, app.dataSignal2_1(1,:), app.restaurar2, 0, app.arraySamplingFrequencyGraph2(1));
                    case 2
                        graphSignal2(app, app.dataSignal2_2(1,:), app.restaurar2, 1, app.arraySamplingFrequencyGraph2(2));
                    case 3
                        graphSignal2(app, app.dataSignal2_3(1,:), app.restaurar2, 3, app.arraySamplingFrequencyGraph2(3));
                    case 4
                        graphSignal2(app, app.dataSignal2_4(1,:), app.restaurar2, 4, app.arraySamplingFrequencyGraph2(4));
                    case 5
                        graphSignal2(app, app.dataSignalResult2(1,:), app.restaurar2, 5, app.arraySamplingFrequencyGraph2(5));
                end
            end
        end

        % Button pushed function: IIRButton
        function IIRButtonPushed(app, event)
            switch app.signalChannelButton1
                case 1
                    if(~isempty(app.dataSignal1_1))
                        differencesEquation(app, app.dataSignal1_1, app.signalChannelButton1, 1);
                    else
                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error');
                        return;
                    end
                case 2
                    if(~isempty(app.dataSignal1_2))
                        differencesEquation(app, app.dataSignal1_2, app.signalChannelButton1, 1);
                    else
                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                        return;
                    end
                case 3
                    if(~isempty(app.dataSignal1_3))
                        differencesEquation(app, app.dataSignal1_3, app.signalChannelButton1, 1);
                    else
                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                        return;
                    end
                case 4
                    if(~isempty(app.dataSignal1_4))
                        differencesEquation(app, app.dataSignal1_4, app.signalChannelButton1, 1);
                    else
                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                        return;
                    end
                case 5
                    if(~isempty(app.dataSignalResult1))
                        differencesEquation(app, app.dataSignalResult1, app.signalChannelButton1, 1);
                    else
                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                        return;
                    end  
            end
        end

        % Button pushed function: IIRButton_2
        function IIRButton_2Pushed(app, event)
            switch app.signalChannelButton2
                case 1
                    if(~isempty(app.dataSignal2_1))
                        differencesEquation(app, app.dataSignal2_1, app.signalChannelButton2, 2);
                    else
                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error');
                        return;
                    end
                case 2
                    if(~isempty(app.dataSignal2_2))
                        differencesEquation(app, app.dataSignal2_2, app.signalChannelButton2, 2);
                    else
                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                        return;
                    end
                case 3
                    if(~isempty(app.dataSignal2_3))
                        differencesEquation(app, app.dataSignal2_3, app.signalChannelButton2, 2);
                    else
                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                        return;
                    end
                case 4
                    if(~isempty(app.dataSignal2_4))
                        differencesEquation(app, app.dataSignal2_4, app.signalChannelButton2, 2);
                    else
                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                        return;
                    end
                case 5
                    if(~isempty(app.dataSignalResult2))
                        differencesEquation(app, app.dataSignalResult2, app.signalChannelButton2, 2);
                    else
                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                        return;
                    end  
            end
        end

        % Button pushed function: ShiftButton
        function ShiftButtonPushed(app, event)
            try
                dlg_title = 'Options';
                prompt = {'Channel 1 (1) Channel 2 (2), Out 1 (3),Out 2 (4), Result 1 (5) '};
                num_lines = 1;
                dims = [1 50];
                defaultans = {'0'};
                answer = inputdlg(prompt,dlg_title,dims,defaultans);
                if ~isempty(answer)
                    canal = str2num(answer{1});
    
                    if ((canal == 1) || (canal == 2)||(canal == 3)||(canal == 4)||(canal == 5))
                        dlg_title = 'Operations';
                        prompt = {'Reflexion (1) Retraso (2) Adelanto (3)'};
                        num_lines = 1;
                        dims = [1 50];
                        defaultans = {'0'};
                        answer1 = inputdlg(prompt,dlg_title,dims,defaultans);
                        operacion = str2num(answer1{1});
    
                        if ((~isempty(answer1)) & ((operacion == 1)||(operacion == 2)||(operacion == 3)))
    
                            switch canal
                                case 1
                                    if(~isempty(app.dataSignal1_1))
                                        app.desplazamiento_tiempo(app.dataSignal1_1(1,:),app.dataSignal1_1(2,:),operacion,0,0);
                                    else
                                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error',0);
                                        return;
                                    end
                                case 2
                                    if(~isempty(app.dataSignal1_2))
                                        app.desplazamiento_tiempo(app.dataSignal1_2(1,:),app.dataSignal1_2(2,:),operacion,1,0);
                                    else
                                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                                        return;
                                    end
                                case 3
                                    if(~isempty(app.dataSignal1_3))
                                        app.desplazamiento_tiempo(app.dataSignal1_3(1,:),app.dataSignal1_3(2,:),operacion,3,0);
                                    else
                                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                                        return;
                                    end
                                case 4
                                    if(~isempty(app.dataSignal1_4))
                                        app.desplazamiento_tiempo(app.dataSignal1_4(1,:),app.dataSignal1_4(2,:),operacion,4,0);
                                    else
                                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                                        return;
                                    end
                                case 5
                                    if(~isempty(app.dataSignalResult1))
                                        app.desplazamiento_tiempo(app.dataSignalResult1(1,:),app.dataSignalResult1(2,:),operacion,5,0);
                                    else
                                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                                        return;
                                    end       
                            end
                        else
                            msgbox('You entered a wrong operation number.', 'Error', 'error');    
    
                        end
                    else
                         msgbox('You entered a wrong channel number.', 'Error', 'error');    
                    end
                end
            catch
            end
        end

        % Button pushed function: ShitfButton2
        function ShitfButton2Pushed(app, event)
            try
                dlg_title = 'Options';
                prompt = {'Channel 1 (1), Channel 2 (2), Out 1 (3),Out 2 (4), Result 1 (5) '};
                num_lines = 1;
                dims = [1 50];
                defaultans = {'0'};
                answer = inputdlg(prompt,dlg_title,dims,defaultans);
                if ~isempty(answer)
                    canal = str2num(answer{1});
    
                    if ((canal == 1)||(canal == 2)||(canal == 3)||(canal == 4)||(canal == 5))
                        dlg_title = 'Operations';
                        prompt = {'Reflexion (1), Retraso (2), Adelanto (3)'};
                        num_lines = 1;
                        dims = [1 50];
                        defaultans = {'0'};
                        answer1 = inputdlg(prompt,dlg_title,dims,defaultans);
                        operacion = str2num(answer1{1});
    
                        if ((~isempty(answer1)) & ((operacion == 1)||(operacion == 2)||(operacion == 3)))
    
                            switch canal
                                case 1
                                    if(~isempty(app.dataSignal2_1))
                                        app.desplazamiento_tiempo(app.dataSignal2_1(1,:),app.dataSignal2_1(2,:),operacion,0,1);
                                    else
                                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error',0);
                                        return;
                                    end
                                case 2
                                    if(~isempty(app.dataSignal2_2))
                                        app.desplazamiento_tiempo(app.dataSignal2_2(1,:),app.dataSignal2_2(2,:),operacion,1,1);
                                    else
                                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                                        return;
                                    end
                                case 3
                                    if(~isempty(app.dataSignal2_3))
                                        app.desplazamiento_tiempo(app.dataSignal2_3(1,:),app.dataSignal2_3(2,:),operacion,3,1);
                                    else
                                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                                        return;
                                    end
                                case 4
                                    if(~isempty(app.dataSignal2_4))
                                        app.desplazamiento_tiempo(app.dataSignal2_4(1,:),app.dataSignal2_4(2,:),operacion,4,1);
                                    else
                                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                                        return;
                                    end
                                case 5
                                    if(~isempty(app.dataSignalResult2))
                                        app.desplazamiento_tiempo(app.dataSignalResult2(1,:),app.dataSignalResult2(2,:),operacion,5,1);
                                    else
                                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                                        return;
                                    end       
                            end
                        else
                            msgbox('You entered a wrong operation number.', 'Error', 'error');    
    
                        end
                    else
                         msgbox('You entered a wrong channel number.', 'Error', 'error');    
                    end
                end
            catch
            end
        end

        % Menu selected function: ChannelMenu
        function ChannelMenuSelected(app, event)
            try
                 if strcmp(app.signalType1,'mono')
                    defaultFolder = fullfile(pwd, 'Grabaciones A1 channel mono');
                    % Verificar si la carpeta predeterminada existe, si no, crearla
                    if ~exist(defaultFolder, 'dir')
                        mkdir(defaultFolder);
                    end
                    % Abrir una ventana de diálogo para que el usuario elija la ubicacion y el nombre del archivo
                    [fileName1, filePath1] = uiputfile('*.wav', 'Guardar archivo de audio', fullfile(defaultFolder, ' Channel_1.wav'));
                    [fileName2, filePath2] = uiputfile('*.wav', 'Guardar archivo de audio', fullfile(defaultFolder, 'Channel_2.wav'));

                    
                    % Crear la ruta completa del archivo
                    audioFileName1 = fullfile(filePath1, fileName1);
                    audioFileName2 = fullfile(filePath2, fileName2);
                      

                    % Guardar la señal como archivo de audio en la ubicacion elegida por el usuario
                    audiowrite(audioFileName1, app.dataSignal1_1(2,:), app.arraySamplingFrequencyGraph1(1));
                    audiowrite(audioFileName2, app.dataSignal1_2(2,:), app.arraySamplingFrequencyGraph1(2));
                 else
                    defaultFolder = fullfile(pwd, 'Grabaciones A1 Channel stereo');
                    % Verificar si la carpeta predeterminada existe, si no, crearla
                    if ~exist(defaultFolder, 'dir')
                        mkdir(defaultFolder);
                    end 
                    [ch1,~,ch2,fs] = app.makePaddingFrecuency(app.dataSignal1_1,app.arraySamplingFrequencyGraph1(1),app.dataSignal1_2,app.arraySamplingFrequencyGraph1(2));
                    %stereo = [app.dataSignal1_1(2,:),app.dataSignal1_2(2,:)];
                    stereo = [ch1(2,:);ch2(2,:)];
                    disp(size(stereo));
                    [fileName, filePath] = uiputfile('*.wav', 'Guardar archivo de audio', fullfile(defaultFolder,  'Channel_stereo.wav'));
                    audioFileName = fullfile(filePath, fileName);
                    %encontrar fs de senal stereo
                    %[,,~,fs] = app.makePaddingFrecuency(app.dataSignal1_1(2,:),app.arraySamplingFrequencyGraph1(1),app.dataSignal1_2(2,:),app.arraySamplingFrequencyGraph1(2));
                    audiowrite(audioFileName, stereo', fs);

                 end    
              catch 
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalProcessorUIFigure = uifigure('Visible', 'off');
            app.SignalProcessorUIFigure.Name = 'Signal Processor';
            app.AddsignalMenu = uimenu(app.SignalProcessorUIFigure);
            app.AddsignalMenu.Text = 'Add signal';
            app.CreateMenu = uimenu(app.AddsignalMenu);
            app.CreateMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateMenuSelected, true);
            app.CreateMenu.Text = 'Create';
            app.SyntheticMenu = uimenu(app.AddsignalMenu);
            app.SyntheticMenu.Text = 'Synthetic';
            app.SineMenu = uimenu(app.SyntheticMenu);
            app.SineMenu.MenuSelectedFcn = createCallbackFcn(app, @SineMenuSelected, true);
            app.SineMenu.Text = 'Sine';
            app.CosineMenu = uimenu(app.SyntheticMenu);
            app.CosineMenu.MenuSelectedFcn = createCallbackFcn(app, @CosineMenuSelected, true);
            app.CosineMenu.Text = 'Cosine';
            app.ChirpMenu = uimenu(app.SyntheticMenu);
            app.ChirpMenu.MenuSelectedFcn = createCallbackFcn(app, @ChirpMenuSelected, true);
            app.ChirpMenu.Text = 'Chirp';
            app.StepMenu = uimenu(app.SyntheticMenu);
            app.StepMenu.MenuSelectedFcn = createCallbackFcn(app, @StepMenuSelected, true);
            app.StepMenu.Text = 'Step';
            app.RampMenu = uimenu(app.SyntheticMenu);
            app.RampMenu.MenuSelectedFcn = createCallbackFcn(app, @RampMenuSelected, true);
            app.RampMenu.Text = 'Ramp';
            app.SawtoothMenu = uimenu(app.SyntheticMenu);
            app.SawtoothMenu.MenuSelectedFcn = createCallbackFcn(app, @SawtoothMenuSelected, true);
            app.SawtoothMenu.Text = 'Sawtooth';
            app.RecordaudioMenu = uimenu(app.AddsignalMenu);
            app.RecordaudioMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordaudioMenuSelected, true);
            app.RecordaudioMenu.Text = 'Record audio';
            app.AudiofileMenu = uimenu(app.AddsignalMenu);
            app.AudiofileMenu.MenuSelectedFcn = createCallbackFcn(app, @AudiofileMenuSelected, true);
            app.AudiofileMenu.Text = 'Audio file';
            app.CaptureMenu = uimenu(app.AddsignalMenu);
            app.CaptureMenu.Text = 'Capture';
            app.SaveMenu = uimenu(app.SignalProcessorUIFigure);
            app.SaveMenu.Text = 'Save';
            app.Signal1Menu = uimenu(app.SaveMenu);
            app.Signal1Menu.Text = 'Signal 1';
            app.ChannelMenu = uimenu(app.Signal1Menu);
            app.ChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChannelMenuSelected, true);
            app.ChannelMenu.Text = 'Channel';
            app.OutMenu = uimenu(app.Signal1Menu);
            app.OutMenu.Text = 'Out';
            app.Result1Menu = uimenu(app.Signal1Menu);
            app.Result1Menu.Text = 'Result 1';
            app.AllMenu = uimenu(app.Signal1Menu);
            app.AllMenu.Text = 'All';
            app.Signal2Menu = uimenu(app.SaveMenu);
            app.Signal2Menu.Text = 'Signal 2';
            app.ChannelMenu_2 = uimenu(app.Signal2Menu);
            app.ChannelMenu_2.Text = 'Channel';
            app.OutMenu_2 = uimenu(app.Signal2Menu);
            app.OutMenu_2.Text = 'Out';
            app.Result2Menu = uimenu(app.Signal2Menu);
            app.Result2Menu.Text = 'Result 2';
            app.AllMenu_2 = uimenu(app.Signal2Menu);
            app.AllMenu_2.Text = 'All';
            app.StandardizationMenu = uimenu(app.SignalProcessorUIFigure);
            app.StandardizationMenu.MenuSelectedFcn = createCallbackFcn(app, @StandardizationMenuSelected, true);
            app.StandardizationMenu.Text = 'Standardization';
            app.ViewMenu = uimenu(app.SignalProcessorUIFigure);
            app.ViewMenu.Text = 'View';
            app.FrecuencyMenu = uimenu(app.ViewMenu);
            app.FrecuencyMenu.Text = 'Frecuency';
            app.Signal1Menu_2 = uimenu(app.FrecuencyMenu);
            app.Signal1Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Signal1Menu_2Selected, true);
            app.Signal1Menu_2.Text = 'Signal 1';
            app.Signal2Menu_2 = uimenu(app.FrecuencyMenu);
            app.Signal2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Signal2Menu_2Selected, true);
            app.Signal2Menu_2.Text = 'Signal 2';
            app.ResultsMenu = uimenu(app.FrecuencyMenu);
            app.ResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenuSelected, true);
            app.ResultsMenu.Text = 'Results';
            app.FiltersMenu = uimenu(app.FrecuencyMenu);
            app.FiltersMenu.MenuSelectedFcn = createCallbackFcn(app, @FiltersMenuSelected, true);
            app.FiltersMenu.Text = 'Filters';
            app.GridLayout = uigridlayout(app.SignalProcessorUIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'h')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'h')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Channel1Tab = uitab(app.TabGroup);
            app.Channel1Tab.Title = 'Channel 1';
            app.Channel1Tab.ButtonDownFcn = createCallbackFcn(app, @Channel1TabButtonDown, true);
            app.GridLayout8 = uigridlayout(app.Channel1Tab);
            app.UIAxes3 = uiaxes(app.GridLayout8);
            xlabel(app.UIAxes3, 'n')
            ylabel(app.UIAxes3, 'x(n)')
            app.Channel2Tab = uitab(app.TabGroup);
            app.Channel2Tab.Title = 'Channel 2';
            app.Channel2Tab.ButtonDownFcn = createCallbackFcn(app, @Channel2TabButtonDown, true);
            app.GridLayout8_2 = uigridlayout(app.Channel2Tab);
            app.UIAxes3_2 = uiaxes(app.GridLayout8_2);
            xlabel(app.UIAxes3_2, 'n')
            ylabel(app.UIAxes3_2, 'x(n)')
            app.Out1Tab = uitab(app.TabGroup);
            app.Out1Tab.Title = 'Out 1';
            app.Out1Tab.ButtonDownFcn = createCallbackFcn(app, @Out1TabButtonDown, true);
            app.GridLayout8_3 = uigridlayout(app.Out1Tab);
            app.UIAxes3_3 = uiaxes(app.GridLayout8_3);
            xlabel(app.UIAxes3_3, 'n')
            ylabel(app.UIAxes3_3, 'x(n)')
            app.Out2Tab = uitab(app.TabGroup);
            app.Out2Tab.Title = 'Out 2';
            app.Out2Tab.ButtonDownFcn = createCallbackFcn(app, @Out2TabButtonDown, true);
            app.GridLayout8_4 = uigridlayout(app.Out2Tab);
            app.UIAxes3_4 = uiaxes(app.GridLayout8_4);
            xlabel(app.UIAxes3_4, 'n')
            ylabel(app.UIAxes3_4, 'x(n)')
            app.Result1Tab = uitab(app.TabGroup);
            app.Result1Tab.Title = 'Result 1';
            app.Result1Tab.ButtonDownFcn = createCallbackFcn(app, @Result1TabButtonDown, true);
            app.GridLayout9 = uigridlayout(app.Result1Tab);
            app.UIAxes3_9 = uiaxes(app.GridLayout9);
            xlabel(app.UIAxes3_9, 'n')
            ylabel(app.UIAxes3_9, 'x(n)')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MultiplyButton = uibutton(app.GridLayout2, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.Playbacksignal1Button = uibutton(app.GridLayout2, 'push');
            app.Playbacksignal1Button.ButtonPushedFcn = createCallbackFcn(app, @Playbacksignal1ButtonPushed, true);
            app.Playbacksignal1Button.Text = 'Play back signal 1';
            app.Playbacksignal2Button = uibutton(app.GridLayout2, 'push');
            app.Playbacksignal2Button.ButtonPushedFcn = createCallbackFcn(app, @Playbacksignal2ButtonPushed, true);
            app.Playbacksignal2Button.Text = 'Play back signal 2';
            app.ConverttostereoButton = uibutton(app.GridLayout2, 'push');
            app.ConverttostereoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttostereoButtonPushed, true);
            app.ConverttostereoButton.Text = 'Convert to stereo';
            app.ConverttomonoButton = uibutton(app.GridLayout2, 'push');
            app.ConverttomonoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttomonoButtonPushed, true);
            app.ConverttomonoButton.Text = 'Convert to mono';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.AddfilterPanel = uipanel(app.GridLayout3);
            app.AddfilterPanel.Title = 'Add filter';
            app.GridLayout6 = uigridlayout(app.AddfilterPanel);
            app.FIRButton = uibutton(app.GridLayout6, 'push');
            app.FIRButton.ButtonPushedFcn = createCallbackFcn(app, @FIRButtonPushed, true);
            app.FIRButton.Text = 'FIR';
            app.IIRButton = uibutton(app.GridLayout6, 'push');
            app.IIRButton.ButtonPushedFcn = createCallbackFcn(app, @IIRButtonPushed, true);
            app.IIRButton.Text = 'IIR';
            app.ApplyButton = uibutton(app.GridLayout6, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlaychannelButton = uibutton(app.GridLayout3, 'push');
            app.PlaychannelButton.ButtonPushedFcn = createCallbackFcn(app, @PlaychannelButtonPushed, true);
            app.PlaychannelButton.Text = 'Play channel';
            app.ShiftButton = uibutton(app.GridLayout3, 'push');
            app.ShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftButtonPushed, true);
            app.ShiftButton.Text = 'Shift';
            app.Panel_6 = uipanel(app.GridLayout3);
            app.GridLayout11 = uigridlayout(app.Panel_6);
            app.MONOLabel = uilabel(app.GridLayout11);
            app.MONOLabel.Text = 'MONO';
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_5);
            app.Knob = uiknob(app.GridLayout5, 'continuous');
            app.Knob.Limits = [-20 20];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.AddfilterPanel_2 = uipanel(app.GridLayout4);
            app.AddfilterPanel_2.Title = 'Add filter';
            app.GridLayout6_2 = uigridlayout(app.AddfilterPanel_2);
            app.FIRButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.FIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @FIRButton_2Pushed, true);
            app.FIRButton_2.Text = 'FIR';
            app.IIRButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.IIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @IIRButton_2Pushed, true);
            app.IIRButton_2.Text = 'IIR';
            app.ApplyButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.PlaychannelButton_2 = uibutton(app.GridLayout4, 'push');
            app.PlaychannelButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlaychannelButton_2Pushed, true);
            app.PlaychannelButton_2.Text = 'Play channel';
            app.ShitfButton2 = uibutton(app.GridLayout4, 'push');
            app.ShitfButton2.ButtonPushedFcn = createCallbackFcn(app, @ShitfButton2Pushed, true);
            app.ShitfButton2.Text = 'Shitf';
            app.Panel_7 = uipanel(app.GridLayout4);
            app.GridLayout12 = uigridlayout(app.Panel_7);
            app.MONOLabel_2 = uilabel(app.GridLayout12);
            app.MONOLabel_2.Text = 'MONO';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.Panel_3);
            app.Switch = uiswitch(app.GridLayout7, 'slider');
            app.Switch.Items = {'Constant', 'Linear'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Constant';
            app.ApplyButton_3 = uibutton(app.GridLayout7, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.Signal1CheckBox = uicheckbox(app.GridLayout7);
            app.Signal1CheckBox.Text = 'Signal 1';
            app.Signal2CheckBox = uicheckbox(app.GridLayout7);
            app.Signal2CheckBox.Text = 'Signal2';
            app.StopsoundButton = uibutton(app.GridLayout7, 'push');
            app.StopsoundButton.ButtonPushedFcn = createCallbackFcn(app, @StopsoundButtonPushed, true);
            app.StopsoundButton.Text = 'Stop sound';
            app.SetVolumeEditFieldLabel = uilabel(app.GridLayout7);
            app.SetVolumeEditFieldLabel.Text = 'Set Volume';
            app.SetVolumeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SetVolumeEditField.ValueChangedFcn = createCallbackFcn(app, @SetVolumeEditFieldValueChanged, true);
            app.RestoreButton = uibutton(app.GridLayout7, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.TabGroup_2 = uitabgroup(app.GridLayout);
            app.Channel1Tab_2 = uitab(app.TabGroup_2);
            app.Channel1Tab_2.Title = 'Channel 1';
            app.Channel1Tab_2.ButtonDownFcn = createCallbackFcn(app, @Channel1Tab_2ButtonDown, true);
            app.GridLayout8_5 = uigridlayout(app.Channel1Tab_2);
            app.UIAxes3_5 = uiaxes(app.GridLayout8_5);
            xlabel(app.UIAxes3_5, 'n')
            ylabel(app.UIAxes3_5, 'x(n)')
            app.Channel2Tab_2 = uitab(app.TabGroup_2);
            app.Channel2Tab_2.Title = 'Channel 2';
            app.Channel2Tab_2.ButtonDownFcn = createCallbackFcn(app, @Channel2Tab_2ButtonDown, true);
            app.GridLayout8_6 = uigridlayout(app.Channel2Tab_2);
            app.UIAxes3_6 = uiaxes(app.GridLayout8_6);
            xlabel(app.UIAxes3_6, 'n')
            ylabel(app.UIAxes3_6, 'x(n)')
            app.Out1Tab_2 = uitab(app.TabGroup_2);
            app.Out1Tab_2.Title = 'Out 1';
            app.Out1Tab_2.ButtonDownFcn = createCallbackFcn(app, @Out1Tab_2ButtonDown, true);
            app.GridLayout8_7 = uigridlayout(app.Out1Tab_2);
            app.UIAxes3_7 = uiaxes(app.GridLayout8_7);
            xlabel(app.UIAxes3_7, 'n')
            ylabel(app.UIAxes3_7, 'x(n)')
            app.Out2Tab_2 = uitab(app.TabGroup_2);
            app.Out2Tab_2.Title = 'Out 2';
            app.Out2Tab_2.ButtonDownFcn = createCallbackFcn(app, @Out2Tab_2ButtonDown, true);
            app.GridLayout8_8 = uigridlayout(app.Out2Tab_2);
            app.UIAxes3_8 = uiaxes(app.GridLayout8_8);
            xlabel(app.UIAxes3_8, 'n')
            ylabel(app.UIAxes3_8, 'x(n)')
            app.Result2Tab = uitab(app.TabGroup_2);
            app.Result2Tab.Title = 'Result 2';
            app.Result2Tab.ButtonDownFcn = createCallbackFcn(app, @Result2TabButtonDown, true);
            app.GridLayout10 = uigridlayout(app.Result2Tab);
            app.UIAxes3_10 = uiaxes(app.GridLayout10);
            xlabel(app.UIAxes3_10, 'n')
            ylabel(app.UIAxes3_10, 'x(n)')
            % Show the figure after all components are created
            app.SignalProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalProcessorUIFigure)
        end
    end
end