classdef OperationSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectUIFigure          matlab.ui.Figure
        CancelButton            matlab.ui.control.Button
        Signal1DropDown_2       matlab.ui.control.DropDown
        Signal1DropDown_2Label  matlab.ui.control.Label
        Signal2DropDown         matlab.ui.control.DropDown
        Signal2Label            matlab.ui.control.Label
        Button                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
        dataSignal1 = [];   %Señal 1.
        dataSignal2 = [];   %Señal 2.
        fs1 = 0;            %Frecuencia de la señal 1.
        fs2 = 0;            %Frecuencia de la señal 2.
        type = 0;           %En que respuesta se grafica la solución.
        channel1 = 0;      %Canal 1 selecionado.
        channel2 = 0;      %Canal 2 selecionado.
    end
    
    methods (Access = private)
      
        %% Función para determinar si modifica frecuencias o no.
        function infoButtonPushed(app)
            % Mensaje de confirmación con botones 'Sí' y 'No'
            respuesta = questdlg('The frequency of one of the signals is modified. Do you want to save the signals with modified frequency?', 'Confirmación', 'Yes', 'No', 'No');
            % Evaluar la respuesta del usuario
            switch respuesta
                case 'Yes'
                    % Regraficar las señales modificadas:
                    graphSignal1(app.CallingApp, app.dataSignal1(1,:), app.dataSignal1(2,:), app.channel1, app.fs1);
                    graphSignal2(app.CallingApp, app.dataSignal2(1,:), app.dataSignal2(2,:), app.channel2, app.fs2);
                case 'No'
                    return;
            end
        end
        
        %% Función para guardar las señales con padding:
        function AskPadding(app)
            % Mensaje de confirmación con botones 'Sí' y 'No'
            respuesta = questdlg('The signs changed their dimensions. Do you want to save the signals with padding?', 'Confirmación', 'Yes', 'No', 'No');
            % Evaluar la respuesta del usuario
            switch respuesta
                case 'Yes'
                    % Regraficar las señales modificadas:
                    graphSignal1(app.CallingApp, app.dataSignal1(1,:), app.dataSignal1(2,:), app.channel1, app.fs1);
                    graphSignal2(app.CallingApp, app.dataSignal2(1,:), app.dataSignal2(2,:), app.channel2, app.fs2);
                case 'No'
                    return;
            end
        end
    end
    
    methods (Static)
        
        %% Sobremuestreo de la señal con interpolación.
        function senalSobreMuestreada = calcularSobremuestreo(factorSobreMuestreo, senalOriginal)
            % Crear un vector con los índices originales y los nuevos índices sobre muestreados
            indicesOriginales = 1:length(senalOriginal);
            nuevosIndices = 1:(1/factorSobreMuestreo):length(senalOriginal);
            % Aplicar interpolación lineal
            senalSobreMuestreada = interp1(indicesOriginales, senalOriginal, nuevosIndices, 'linear');
        end
        
        %% Submuestreo de la señal:
        function senalSubmuestreada = calcularSubmuestreo(factorSubmuestreo, senalOriginal)
            % Aplicar submuestreo utilizando downsample
            senalSubmuestreada = downsample(senalOriginal, factorSubmuestreo);
        end
    
        %% Función para realiza padding:
        function [signal1, signal2] = paddingDigital(signal1, signal2)
            % Corregir limites inferiores:
            li = abs(abs(signal1(1,1)) - abs(signal2(1,1)));
            if(signal1(1,1) > signal2(1,1))
                signal1 = [signal2(1,1):1:signal1(1,end); [zeros(1, li) signal1(2,:)]];
            elseif(signal1(1,1) < signal2(1,1))
                signal2 = [signal1(1,1):1:signal2(1,end); [zeros(1, li) signal2(2,:)]];
            end
            % Corregir limites Superiores:
            lm = abs(abs(signal1(1,end)) - abs(signal2(1,end)));
            if(signal1(1,end) > signal2(1,end))
                signal2 = [signal2(1,1):1:signal1(1,end); [signal2(2,:) zeros(1, lm)]];
            elseif(signal1(1,end) < signal2(1,end))
                signal1 = [signal1(1,1):1:signal2(1,end); [signal1(2,:) zeros(1, lm)]];
            end
        end

        %% Modificador de frecuencias para las señales sean operables:
        function [signal1, fs1, signal2, fs2] = changeFrecuency(signal1, fs1, signal2, fs2)
            %Determinar la frecuencia mayor:
            if(fs1 > fs2)
                frecuenciaMuestreoMayor = fs1;
                frecuenciaMuestreoMenor = fs2;
            else
                frecuenciaMuestreoMayor = fs2;
                frecuenciaMuestreoMenor = fs1;
            end
            % Simplificar la fracción
            fraccionSimplificada = rats(frecuenciaMuestreoMayor/frecuenciaMuestreoMenor);
            [numeradorSimplificado, denominadorSimplificado] = sscanf(fraccionSimplificada, '%d/%d');
            if(fs1 < fs2)
                newSenal1 = OperationSignals.calcularSobremuestreo(numeradorSimplificado, signal1(2,:));
                newSenal1 = OperationSignals.calcularSubmuestreo(denominadorSimplificado, newSenal1);
                newTsenal1 = signal1(1,1):1:(length(newSenal1) - 1 + signal1(1,1));
                signal1 = [newTsenal1; newSenal1];
                fs1 = fs2;
            else
                newSenal2 = OperationSignals.calcularSobremuestreo(numeradorSimplificado, signal2(2,:));
                newSenal2 = OperationSignals.calcularSubmuestreo(denominadorSimplificado, newSenal2);
                newTsenal2 = signal2(1,1):1:(length(newSenal2) - 1 + signal2(1,1));
                signal2 = [newTsenal2; newSenal2];
                fs2 = fs1;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, operation, type1, type2)
            movegui(app.SelectUIFigure, 'center');
            app.CallingApp = caller;
            switch operation
                case 0
                    app.Button.Text = "Add";
                case 1
                    app.Button.Text = "Multiply";
            end
            switch type1
                case 1
                    app.Signal1DropDown_2.ValueIndex = 1;
                case 2
                    app.Signal1DropDown_2.ValueIndex = 2;
                case 3
                    app.Signal1DropDown_2.ValueIndex = 3;
                case 4
                    app.Signal1DropDown_2.ValueIndex = 4;
                case 5
                    app.Signal1DropDown_2.ValueIndex = 5;
            end
            switch type2
                case 1
                    app.Signal2DropDown.ValueIndex = 1;
                case 2
                    app.Signal2DropDown.ValueIndex = 2;
                case 3
                    app.Signal2DropDown.ValueIndex = 3;
                case 4
                    app.Signal2DropDown.ValueIndex = 4;
                case 5
                    app.Signal2DropDown.ValueIndex = 5;
            end
            Signal1DropDown_2ValueChanged(app);
            Signal2DropDownValueChanged(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            select = questdlg('Select the channel to save', ' Select Channel', ...
                'Result 1', 'Result 2', 'Cancel', ...
                'Result 1');
            switch select
                case 'Result 1'
                    app.type = 1;
                case 'Result 2'
                    app.type = 2;
                case 'Cancel'
                    return;
            end
            %Determinar si las dos señales son de diferente de frecuencia:
            if(app.fs1 ~= app.fs2)
                % Realizar modificación de frencuencias.
                [app.dataSignal1, app.fs1, app.dataSignal2, app.fs2] = OperationSignals.changeFrecuency(app.dataSignal1, app.fs1, app.dataSignal2, app.fs2);
                infoButtonPushed(app);
            end
            % Realizar padding:
            if~((app.dataSignal1(1,1) == app.dataSignal2(1,1)) && (app.dataSignal1(1, end) == app.dataSignal2(1, end)))
                %Realizar Padding:
                [app.dataSignal1, app.dataSignal2]= OperationSignals.paddingDigital(app.dataSignal1, app.dataSignal2);
                AskPadding(app);
            end
            %Aplicar la operación:
            switch app.Button.Text
                case 'Add'
                    result = app.dataSignal1(2,:) + app.dataSignal2(2,:);
                    if app.type == 1
                        graphSignal1(app.CallingApp, app.dataSignal1(1,:), result, 5, app.fs1);
                    else
                        graphSignal2(app.CallingApp, app.dataSignal2(1,:), result, 5, app.fs2);
                    end
                case 'Multiply'
                    result = app.dataSignal1(2,:).*app.dataSignal2(2,:);
                    if app.type == 1
                        graphSignal1(app.CallingApp, app.dataSignal1(1,:), result, 5, app.fs1);
                    else
                        graphSignal2(app.CallingApp, app.dataSignal2(1,:), result, 5, app.fs2);
                    end
            end
            delete(app);
        end

        % Value changed function: Signal1DropDown_2
        function Signal1DropDown_2ValueChanged(app, event)
            value = app.Signal1DropDown_2.Value;
            switch value
                case "Channel 1"
                    if(isempty(app.CallingApp.dataSignal1_1))
                        msgbox('No signal selected on Channel 1.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal1 = app.CallingApp.dataSignal1_1;
                        app.fs1 = app.CallingApp.arraySamplingFrequencyGraph1(1);
                        app.channel1 = 0;
                    end
                case "Channel 2"
                    if(isempty(app.CallingApp.dataSignal1_2))
                        msgbox('No signal selected on Channel 2.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal1 = app.CallingApp.dataSignal1_2;
                        app.fs1 = app.CallingApp.arraySamplingFrequencyGraph1(2);
                        app.channel1 = 1;
                    end
                case "Out 1"
                    if(isempty(app.CallingApp.dataSignal1_3))
                        msgbox('No signal selected on Out 1.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal1 = app.CallingApp.dataSignal1_3;
                        app.fs1 = app.CallingApp.arraySamplingFrequencyGraph1(3);
                        app.channel1 = 3;
                    end
                case "Out 2"
                    if(isempty(app.CallingApp.dataSignal1_4))
                        msgbox('No signal selected on Out 2.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal1 = app.CallingApp.dataSignal1_4;
                        app.fs1 = app.CallingApp.arraySamplingFrequencyGraph1(4);
                        app.channel1 = 4;
                    end
                case "Result 1"
                    if(isempty(app.CallingApp.dataSignalResult1))
                        msgbox('No signal selected on Result 1.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal1 = app.CallingApp.dataSignalResult1;
                        app.fs1 = app.CallingApp.arraySamplingFrequencyGraph1(5);
                        app.channel1 = 5;
                    end
            end
        end

        % Value changed function: Signal2DropDown
        function Signal2DropDownValueChanged(app, event)
            value = app.Signal2DropDown.Value;
            switch value
                case "Channel 1"
                    if(isempty(app.CallingApp.dataSignal2_1))
                        msgbox('No signal selected on Channel 1.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal2 = app.CallingApp.dataSignal2_1;
                        app.fs2 = app.CallingApp.arraySamplingFrequencyGraph2(1);
                        app.channel2 = 0;
                    end
                case "Channel 2"
                    if(isempty(app.CallingApp.dataSignal2_2))
                        msgbox('No signal selected on Channel 2.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal2 = app.CallingApp.dataSignal2_2;
                        app.fs2 = app.CallingApp.arraySamplingFrequencyGraph2(2);
                        app.channel2 = 1;
                    end
                case "Out 1"
                    if(isempty(app.CallingApp.dataSignal2_3))
                        msgbox('No signal selected on Out 1.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal2 = app.CallingApp.dataSignal2_3;
                        app.fs2 = app.CallingApp.arraySamplingFrequencyGraph2(3);
                        app.channel2 = 3;
                    end
                case "Out 2"
                    if(isempty(app.CallingApp.dataSignal2_4))
                        msgbox('No signal selected on Out 2.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal2 = app.CallingApp.dataSignal2_4;
                        app.fs2 = app.CallingApp.arraySamplingFrequencyGraph2(4);
                        app.channel2 = 4;
                    end
                case "Result 2"
                    if(isempty(app.CallingApp.dataSignalResult2))
                        msgbox('No signal selected on Result 2.', 'Error', 'error');
                        return;
                    else
                        app.dataSignal2 = app.CallingApp.dataSignalResult2;
                        app.fs2 = app.CallingApp.arraySamplingFrequencyGraph2(5);
                        app.channel2 = 5;
                    end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectUIFigure = uifigure('Visible', 'off');
            app.SelectUIFigure.Name = 'Select';
            app.Button = uibutton(app.SelectUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Signal2Label = uilabel(app.SelectUIFigure);
            app.Signal2Label.Text = 'Signal 2';
            app.Signal2DropDown = uidropdown(app.SelectUIFigure);
            app.Signal2DropDown.Items = {'Channel 1', 'Channel 2', 'Out 1', 'Out 2', 'Result 2'};
            app.Signal2DropDown.ValueChangedFcn = createCallbackFcn(app, @Signal2DropDownValueChanged, true);
            app.Signal2DropDown.Value = 'Channel 1';
            app.Signal1DropDown_2Label = uilabel(app.SelectUIFigure);
            app.Signal1DropDown_2Label.Text = 'Signal 1';
            app.Signal1DropDown_2 = uidropdown(app.SelectUIFigure);
            app.Signal1DropDown_2.Items = {'Channel 1', 'Channel 2', 'Out 1', 'Out 2', 'Result 1'};
            app.Signal1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Signal1DropDown_2ValueChanged, true);
            app.Signal1DropDown_2.Value = 'Channel 1';
            app.CancelButton = uibutton(app.SelectUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SelectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperationSignals(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectUIFigure)
        end
    end
end