classdef ViewFrecuency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViewFrecuencyUIFigure  matlab.ui.Figure
        Signal1Panel           matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % Description
        graph1 = []
        graph2 = []
        graph3 = []
        graph4 = []
        fs = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, option, input1, input2, input3, input4, fs)
            movegui(app.ViewFrecuencyUIFigure, 'center');
            app.CallingApp = caller;
            switch option
                case 1
                    if ~isempty(app.CallingApp.dataSignal1_1)
                        app.UIAxes.Title.String = "Channel 1";
                        signal = input1(2,:);
                        fs1 = fs(1);
                        N = length(signal);
                        if N > 100000
                            N = 16384*2;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        magnitude(magnitude == 0) = eps;
                        stem(app.UIAxes,f_pos, 20*log10(magnitude),".");
                    end

                    if ~isempty(app.CallingApp.dataSignal1_2)
                        app.UIAxes_2.Title.String = "Channel 2";
                        signal = input2(2,:);
                        fs1 = fs(2);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_2,f_pos, magnitude,".");
                    end

                      if ~isempty(app.CallingApp.dataSignal1_3)
                        app.UIAxes_3.Title.String = "Out 1";
                        signal = input3(2,:);
                        fs1 = fs(3);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        magnitude(magnitude == 0) = eps;
                        stem(app.UIAxes_3,f_pos, 20*log10(magnitude),".");
                      end

                    if ~isempty(app.CallingApp.dataSignal1_4)
                        app.UIAxes_4.Title.String = "Out 2";
                        signal = input4(2,:);
                        fs1 = fs(4);
                        N = length(signal);
                        if N > 100000
                            N = 16384*2;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_4,f_pos, magnitude,".");
                    end

                case 2
                    app.Signal1Panel.Title = "Signal 2";
                    if ~isempty(app.CallingApp.dataSignal2_1)
                        app.UIAxes.Title.String = "Channel 1";
                        signal = input1(2,:);
                        fs1 = fs(1);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes,f_pos, magnitude,".");
                    end

                    if ~isempty(app.CallingApp.dataSignal2_2)
                        app.UIAxes_2.Title.String = "Channel 2";
                        signal = input2(2,:);
                        fs1 = fs(2);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_2,f_pos, magnitude,".");
                    end

                      if ~isempty(app.CallingApp.dataSignal2_3)
                        app.UIAxes_3.Title.String = "Out 1";
                        signal = input3(2,:);
                        fs1 = fs(3);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_3,f_pos, magnitude,".");
                      end

                    if ~isempty(app.CallingApp.dataSignal2_4)
                        app.UIAxes_4.Title.String = "Out 2";
                        signal = input4(2,:);
                        fs1 = fs(4);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_4,f_pos, magnitude,".");
                    end

                case 3
                    app.Signal1Panel.Title = "Results and Filters";
                    if ~isempty(app.CallingApp.dataSignalResult1)
                        app.UIAxes.Title.String = "Result 1";
                        signal = input1(2,:);
                        fs1 = fs(1);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes,f_pos, magnitude,".");
                    end

                    if ~isempty(app.CallingApp.dataSignalResult2)
                        app.UIAxes_2.Title.String = "Result 2";
                        signal = input2(2,:);
                        fs1 = fs(2);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_2,f_pos, magnitude,".");
                    end

                      if ~isempty(app.CallingApp.dataSignal1_3)
                        app.UIAxes_3.Title.String = "Out 1";
                        signal = input3(2,:);
                        fs1 = fs(3);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_3,f_pos, magnitude,".");
                      end

                    if ~isempty(app.CallingApp.dataSignal1_4)
                        app.UIAxes_4.Title.String = "Out 2";
                        signal = input4(2,:);
                        fs1 = fs(4);
                        N = length(signal);
                        if N > 100000
                            N = 16384;
                            fragment_size = 1024;
                        else
                            fragment_size = N;
                        end
                        num_fragments = ceil(length(signal) / fragment_size);
                        fft_result = zeros(1, N);
                        for i = 1:num_fragments
                            start_idx = (i - 1) * fragment_size + 1;
                            end_idx = min(i * fragment_size, length(signal));
                            fragment = signal(start_idx:end_idx);
                        
                            fft_result_fragment = fftshift(fft(fragment, N));
                            fft_result = fft_result + fft_result_fragment;
                        end
                        f_pos = (0:(fs1/N):(fs1/2)-(fs1/N));
                        magnitude = abs(fft_result(N/2+1:end)/N);
                        stem(app.UIAxes_4,f_pos, magnitude,".");
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ViewFrecuencyUIFigure = uifigure('Visible', 'off');
            app.ViewFrecuencyUIFigure.Name = 'View Frecuency';
            app.Signal1Panel = uipanel(app.ViewFrecuencyUIFigure);
            app.Signal1Panel.Title = 'Signal 1';
            app.GridLayout = uigridlayout(app.Signal1Panel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Channel 1')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Channel 2')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Out 1')
            xlabel(app.UIAxes_3, 'Frequency (Hz)')
            ylabel(app.UIAxes_3, 'Magnitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Out 2')
            xlabel(app.UIAxes_4, 'Frequency (Hz)')
            ylabel(app.UIAxes_4, 'Magnitude')
            zlabel(app.UIAxes_4, 'Z')
            % Show the figure after all components are created
            app.ViewFrecuencyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewFrecuency(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViewFrecuencyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViewFrecuencyUIFigure)
        end
    end
end