classdef convolutionBlocks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConvolutionalBlocksUIFigure  matlab.ui.Figure
        DefaultfiltersMenu           matlab.ui.container.Menu
        Filter1Menu                  matlab.ui.container.Menu
        Filter2Menu                  matlab.ui.container.Menu
        Filter3Menu                  matlab.ui.container.Menu
        Filter4Menu                  matlab.ui.container.Menu
        Lamp                         matlab.ui.control.Lamp
        FilewithhnButton             matlab.ui.control.StateButton
        EntertheimpulseresponsecoefficientsseparatedbyLabel  matlab.ui.control.Label
        ParametersLabel              matlab.ui.control.Label
        CancelButton                 matlab.ui.control.Button
        SizeBlockEditField           matlab.ui.control.NumericEditField
        SizeBlockEditFieldLabel      matlab.ui.control.Label
        hnTextArea                   matlab.ui.control.TextArea
        hnTextAreaLabel              matlab.ui.control.Label
        FiniteImpulseResponseLabel   matlab.ui.control.Label
        ZeroEditField                matlab.ui.control.NumericEditField
        ZeroEditFieldLabel           matlab.ui.control.Label
        ConvolutionButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property
        hn = [];           %Señal h(n).
        n = [];            %eje horizontal para la señal h(n).
        zero = 0;          %Posición inicial del arreglo.
        size_Block = 0;    %Tamaño del bloque.
        xn = [];           %Señal a convolucionar.
        channel = 1;       %Channel de la señal a convolucionar.
        signal = 1;        %Determinar que tipo de señal le entra.  
    end


    methods (Access = private)

        function graph(app)
            stem(app.UIAxes, app.n, app.hn)
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XGrid = 'on';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initfunciton(app, caller, xn, channel, hn, zero, signal)
            movegui(app.ConvolutionalBlocksUIFigure, 'center');
            app.Property = caller;
            if(isempty(hn))
                app.hn = str2double(strsplit(char(app.hnTextArea.Value), ';'));
                app.n = 0:1:length(app.hn)-1;
            else
                app.hn = hn(1, :);
                app.n = hn(2, :);
                app.zero = zero;
                app.ZeroEditField.Value = zero;
                cadena_resultante = sprintf('%d;', app.hn);
                app.hnTextArea.Value = cadena_resultante(1:end-1);
            end
            app.size_Block = app.SizeBlockEditField.Value;
            app.xn = xn;
            graph(app);
            app.channel = channel;
            app.signal = signal;
            app.Lamp.Color = [1.00,0.00,0.00];
        end

        % Value changed function: hnTextArea
        function hnTextAreaValueChanged(app, event)
            try
                % Obtener el valor del TextArea
                value = char(app.hnTextArea.Value);
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end

            % Verificar si el valor está vacío
            if isempty(value)
                % Manejar el caso de valor vacío
                msgbox('Enter values in the TextArea.', 'Error', 'error');
                return;
            end

            % Convertir a un arreglo
            try
                celulasNumeros = strsplit(value, ';');
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end

            app.UIAxes.Title.String = "h1(n)";

            % Verificar si todos los elementos son números válidos (incluyendo notación científica)
            if all(cellfun(@(x) ~isempty(x) && all(isstrprop(x, 'digit') | x == '-' | x == '.' | x == 'e' | x == 'E'), celulasNumeros))
                % Convertir a números
                app.hn = str2double(celulasNumeros);
                app.n = app.zero:1:(length(app.hn) + app.zero - 1);
                % Llamar a la función de actualización del gráfico (si es necesario)
                graph(app);
            else
                % Manejar el caso de caracteres no válidos
                msgbox('Enter only numbers separated by semicolons in scientific notation.', 'Error', 'error');
            end
        end

        % Value changed function: SizeBlockEditField
        function SizeBlockEditFieldValueChanged(app, event)
            app.size_Block = app.SizeBlockEditField.Value;
            if(app.size_Block < 3)
                msgbox('Block size must be greater than 2', 'Error', 'error');
                return;
            end
        end

        % Value changed function: ZeroEditField
        function ZeroEditFieldValueChanged(app, event)
            app.zero = app.ZeroEditField.Value;
            app.n = app.zero:1:(length(app.hn) + app.zero - 1);
            graph(app);
        end

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            % Longitud de entrada:
            ni_x = app.xn(1,1);
            Lx = length(app.xn(1,:));
            if(app.size_Block < 3)
                msgbox('Block size must be greater than 2', 'Error', 'error');
                return;
            end
            nf_x = Lx + ni_x-1;
            %Longitud de h:
            ni_h = app.zero;
            Lh = length(app.hn);
            nf_h = Lh+ni_h-1;
            %Definir instantes y tamaño  del vector de salida y(n)
            Ly = Lx+Lh-1;
            ni_y = ni_x+ni_h;
            nf_y = nf_x+nf_h;
            yinsN = ni_y:1:nf_y;
            y = zeros(1,Ly);
            %Tamaño del bloque:
            Lxb = app.size_Block;
            % Tamaño salida de bloque yb(n)
            Lyb = Lxb+Lh-1 ;
            % Primer bloque
            xb = app.xn(2,:);
            if(Lxb > size(xb))
                msgbox('The block size is too large, it needs to be minimized.', 'Error', 'error');
                return;
            else
                xb = xb(1 : Lxb);
                y(1 : Lyb)=conv(app.hn,xb);
            end
            % Número de bloques
            Nb= floor(Lx/Lxb);
            % Arreglo con los valores de X:
            xArray = app.xn(2,:);
            if(Nb < 150)
                app.Lamp.Color = [0.00,1.00,0.00];
                %Convolución:
                if(app.signal == 1)
                    app.UIAxes.Title.String = "Convolution between signal 1 and h(n)";
                else
                    app.UIAxes.Title.String = "Convolution between signal 2 and h(n)";
                end
                for k=2: Nb
                    xb = xArray(1+(k-1)* Lxb :  Lxb*k ) ;
                    yb=conv(app.hn,xb);
                    y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb)= y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb) + yb;

                    % Visualizar salida por bloques
                    stem(app.UIAxes, y(1 : (k-1)*Lxb + Lyb));
                    app.UIAxes.YGrid = 'on';
                    app.UIAxes.XGrid = 'on';
                    pause(0.001);
                end
            else
                msgbox('The block size is too small, it is necessary to increase the block size.', 'Error', 'error');
                return;
            end

            % Calcular la convolución para el bloque incompleto y adicionarlo a y(n)
            Lxr= mod(Lx,Lxb);

            % verificar si la longitud de x es múltiplo de Nb
            if Lxr ~= 0 % Si hay un bloque incompleto
                xbr = xArray(1+ Nb*Lxb : Lx) ;
                ybr = conv(app.hn,xbr);
                y( 1+Nb*Lxb: Ly)= y( 1+Nb*Lxb: Ly) + ybr;
            end
            % Visualizar salida por bloques
            stem(app.UIAxes, y);
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XGrid = 'on';
            app.Lamp.Color = [1.00,0.00,0.00];
            savehnConvolucion(app.Property, app.hn, app.n, app.channel, app.zero, [y; yinsN], app.signal, app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Menu selected function: Filter1Menu
        function Filter1MenuSelected(app, event)
            app.hn = [-0.000779024944408449136062921880352405424
                0.000999731876142587964212404827435420884
                0.003831402877460619166294364390523696784
                0.006331215011077922713567112111832102528
                0.004203293161063792818255002714522561291
                -0.006457016133813054704904121905428837636
                -0.023107528738289289521601332921818539035
                -0.033457559743661630102806725517439190298
                -0.019904587044922469885044336024293443188
                0.028977468595656773187041466144364676438
                0.107219132728444313529259090955747524276
                0.189581211105815133910468262001813855022
                0.242562261249433724907831333439389709383
                0.242562261249433724907831333439389709383
                0.189581211105815133910468262001813855022
                0.107219132728444313529259090955747524276
                0.028977468595656773187041466144364676438
                -0.019904587044922469885044336024293443188
                -0.033457559743661630102806725517439190298
                -0.023107528738289289521601332921818539035
                -0.006457016133813054704904121905428837636
                0.004203293161063792818255002714522561291
                0.006331215011077922713567112111832102528
                0.003831402877460619166294364390523696784
                0.000999731876142587964212404827435420884
                -0.000779024944408449136062921880352405424]';
            cadena_resultante = sprintf('%d;', app.hn);
            app.hnTextArea.Value = cadena_resultante(1:end-1);
            app.zero = 0;
            app.ZeroEditField.Value = 0;
            app.n = app.zero:1:(length(app.hn) - 1);
            graph(app);
        end

        % Menu selected function: Filter2Menu
        function Filter2MenuSelected(app, event)
            app.hn = [0.001884975120330046596470174868898084242
                0.002419010748058414775329971746486990014
                0.001590599013349243634821150727987060236
                -0.002628390871960992539180246296837140108
                -0.010170538297814175898725608249151264317
                -0.015623780536385257697307160640320944367
                -0.009593042963005444026536316926012659678
                0.01388983701776957437046267074265415431
                0.048162323465281500767254385664273286238
                0.070115607651607611550126364363677566871
                0.044511802121696433798270220449921907857
                -0.078704249325595526665111378861183766276
                -0.586918083776343801893915497203124687076
                0.586918083776343801893915497203124687076
                0.078704249325595526665111378861183766276
                -0.044511802121696433798270220449921907857
                -0.070115607651607611550126364363677566871
                -0.048162323465281500767254385664273286238
                -0.01388983701776957437046267074265415431
                0.009593042963005444026536316926012659678
                0.015623780536385257697307160640320944367
                0.010170538297814175898725608249151264317
                0.002628390871960992539180246296837140108
                -0.001590599013349243634821150727987060236
                -0.002419010748058414775329971746486990014
                -0.001884975120330046596470174868898084242]';
            cadena_resultante = sprintf('%d;', app.hn);
            app.hnTextArea.Value = cadena_resultante(1:end-1);
            app.zero = 0;
            app.ZeroEditField.Value = 0;
            app.n = app.zero:1:(length(app.hn) - 1);
            graph(app);
        end

        % Menu selected function: Filter3Menu
        function Filter3MenuSelected(app, event)
            app.hn = [-0.000000000000000001922929161567581996809
                -0.001569897304044256787849964673853264685
                0.001799201069399839880008640236042083416
                0.008990994433931174395491225936893897597
                0.010792541725120091689049672822875436395
                -0.000000000000000009354657166252341208504
                -0.015835346743411488762420802345332049299
                -0.019167509184833779467549419450733694248
                -0.005459965985622059446435994090052190586
                0.006568207170954977990040557500606155372
                -0.000000000000000005317090080755072521592
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                -0.001569897304044256787849964673853264685
                -0.000000000000000001922929161567581996809]';
            cadena_resultante = sprintf('%d;', app.hn);
            app.hnTextArea.Value = cadena_resultante(1:end-1);
            app.zero = 0;
            app.ZeroEditField.Value = 0;
            app.n = app.zero:1:(length(app.hn) - 1);
            graph(app);
        end

        % Menu selected function: Filter4Menu
        function Filter4MenuSelected(app, event)
            app.hn = [-0.000000000000000007663637615541334456606
                0.001564166308394175945381543435530602437
                -0.001792632987859845957248627890123771067
                -0.008958172318842353917811571761831146432
                -0.010753142963480786728491800374740705593
                0.000000000000000054897901653096777820552
                0.015777538947276896486027197852308745496
                0.019097537148141507240550751589580613654
                0.005440034082284975550103389707601309055
                -0.006544229572784126917894820252286081086
                -0.000000000000000021190719014775402505014
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                0.001564166308394175945381543435530602437
                -0.000000000000000007663637615541334456606]';
            cadena_resultante = sprintf('%d;', app.hn);
            app.hnTextArea.Value = cadena_resultante(1:end-1);
            app.zero = 0;
            app.ZeroEditField.Value = 0;
            app.n = app.zero:1:(length(app.hn) - 1);
            graph(app);
        end

        % Value changed function: FilewithhnButton
        function FilewithhnButtonValueChanged(app, event)
            FileName = uigetfile('*.txt', 'Select the text file');
            if FileName == 0
                % User canceled file selection
                drawnow;
                return;
            end
            fileID = fopen(FileName, 'r'); % Open file for reading
            try
                if fileID == -1
                    error('Error opening the file.');
                end
                % Read values as floating-point numbers
                app.hn = fscanf(fileID, '%f');
                % Display the read values
                app.hn = app.hn'; % Transpose if necessary
                fclose(fileID); % Close the file when done
            catch
                msgbox('The file contains invalid values', 'Error', 'error');
                return;
            end
            %Procesar el archivo:
            cadena_resultante = sprintf('%d;', app.hn);
            app.hnTextArea.Value = cadena_resultante(1:end-1);
            app.zero = 0;
            app.ZeroEditField.Value = 0;
            app.n = app.zero:1:(length(app.hn) - 1);
            graph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConvolutionalBlocksUIFigure = uifigure('Visible', 'off');
            app.ConvolutionalBlocksUIFigure.Name = 'Convolutional Blocks';
            app.DefaultfiltersMenu = uimenu(app.ConvolutionalBlocksUIFigure);
            app.DefaultfiltersMenu.Text = 'Default filters';
            app.Filter1Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter1Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter1MenuSelected, true);
            app.Filter1Menu.Text = 'Filter 1';
            app.Filter2Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter2Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter2MenuSelected, true);
            app.Filter2Menu.Text = 'Filter 2';
            app.Filter3Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter3Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter3MenuSelected, true);
            app.Filter3Menu.Text = 'Filter 3';
            app.Filter4Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter4Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter4MenuSelected, true);
            app.Filter4Menu.Text = 'Filter 4';
            app.UIAxes = uiaxes(app.ConvolutionalBlocksUIFigure);
            title(app.UIAxes, 'h(n)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConvolutionButton = uibutton(app.ConvolutionalBlocksUIFigure, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Convolution ';
            app.ZeroEditFieldLabel = uilabel(app.ConvolutionalBlocksUIFigure);
            app.ZeroEditFieldLabel.Text = 'Zero';
            app.ZeroEditField = uieditfield(app.ConvolutionalBlocksUIFigure, 'numeric');
            app.ZeroEditField.ValueChangedFcn = createCallbackFcn(app, @ZeroEditFieldValueChanged, true);
            app.FiniteImpulseResponseLabel = uilabel(app.ConvolutionalBlocksUIFigure);
            app.FiniteImpulseResponseLabel.Text = 'Finite Impulse Response';
            app.hnTextAreaLabel = uilabel(app.ConvolutionalBlocksUIFigure);
            app.hnTextAreaLabel.Text = 'h(n)';
            app.hnTextArea = uitextarea(app.ConvolutionalBlocksUIFigure);
            app.hnTextArea.ValueChangedFcn = createCallbackFcn(app, @hnTextAreaValueChanged, true);
            app.hnTextArea.Value = {'1;2;3;4;5;6;7'};
            app.SizeBlockEditFieldLabel = uilabel(app.ConvolutionalBlocksUIFigure);
            app.SizeBlockEditFieldLabel.Text = 'Size Block';
            app.SizeBlockEditField = uieditfield(app.ConvolutionalBlocksUIFigure, 'numeric');
            app.SizeBlockEditField.ValueChangedFcn = createCallbackFcn(app, @SizeBlockEditFieldValueChanged, true);
            app.SizeBlockEditField.Value = 4;
            app.CancelButton = uibutton(app.ConvolutionalBlocksUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ParametersLabel = uilabel(app.ConvolutionalBlocksUIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.EntertheimpulseresponsecoefficientsseparatedbyLabel = uilabel(app.ConvolutionalBlocksUIFigure);
            app.EntertheimpulseresponsecoefficientsseparatedbyLabel.Text = 'Enter the impulse response coefficients separated by ";".';
            app.FilewithhnButton = uibutton(app.ConvolutionalBlocksUIFigure, 'state');
            app.FilewithhnButton.ValueChangedFcn = createCallbackFcn(app, @FilewithhnButtonValueChanged, true);
            app.FilewithhnButton.Text = 'File with h(n)';
            app.Lamp = uilamp(app.ConvolutionalBlocksUIFigure);
            % Show the figure after all components are created
            app.ConvolutionalBlocksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolutionBlocks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConvolutionalBlocksUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initfunciton(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConvolutionalBlocksUIFigure)
        end
    end
end