classdef differencesEquation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DefaultfiltersMenu            matlab.ui.container.Menu
        Filter1Menu                   matlab.ui.container.Menu
        Filter2Menu                   matlab.ui.container.Menu
        Filter3Menu                   matlab.ui.container.Menu
        Filter4Menu                   matlab.ui.container.Menu
        EntertheimpulseresponsecoefficientsseparatedbyLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        bkEditField                   matlab.ui.control.EditField
        bkEditFieldLabel              matlab.ui.control.Label
        InfiniteImpulseResponseLabel  matlab.ui.control.Label
        akEditField                   matlab.ui.control.EditField
        akEditFieldLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Calling
        ak = [];            % Numerador.
        bk = [];            % Denominador.
        datasignal = [];    % Señal de entrada.
        channel = 1;        % Channel de la señal a convolucionar.
        signal = 1;         % Determinar que tipo de señal le entra.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling, datasignal, channel, signal)
            movegui(app.UIFigure, 'center');
            app.Calling = calling;
            app.signal = signal;
            app.datasignal = datasignal;
            app.channel = channel;
            app.signal = signal;
            app.ak = str2double(strsplit(char(app.akEditField.Value), ';'));
            app.bk = str2double(strsplit(char(app.bkEditField.Value), ';'));
            h = impz(app.ak, app.bk);
            stem(app.UIAxes,0:length(h)-1, h);
        end

        % Value changed function: akEditField
        function akEditFieldValueChanged(app, event)
            try
                % Obtener el valor del TextArea
                value = char(app.akEditField.Value);
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end

            % Verificar si el valor está vacío
            if isempty(value)
                % Manejar el caso de valor vacío
                msgbox('Enter values in the TextArea.', 'Error', 'error');
                return;
            end

            % Convertir a un arreglo
            try
                celulasNumeros = strsplit(value, ';');
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end
            % Verificar si todos los elementos son números válidos (incluyendo notación científica)
            if all(cellfun(@(x) ~isempty(x) && all(isstrprop(x, 'digit') | x == '-' | x == '.' | x == 'e' | x == 'E'), celulasNumeros))
                % Convertir a números
                app.ak = str2double(celulasNumeros);
                h = impz(app.ak, app.bk);
                stem(app.UIAxes,0:length(h)-1, h);
            else
                % Manejar el caso de caracteres no válidos
                msgbox('Enter only numbers separated by semicolons in scientific notation.', 'Error', 'error');
            end
        end

        % Value changed function: bkEditField
        function bkEditFieldValueChanged(app, event)
            try
                % Obtener el valor del TextArea
                value = char(app.bkEditField.Value);
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end

            % Verificar si el valor está vacío
            if isempty(value)
                % Manejar el caso de valor vacío
                msgbox('Enter values in the TextArea.', 'Error', 'error');
                return;
            end

            % Convertir a un arreglo
            try
                celulasNumeros = strsplit(value, ';');
            catch
                % Manejar el caso en el que no se puede convertir a char
                msgbox('Line breaks are not allowed.', 'Error', 'error');
                return;
            end
            % Verificar si todos los elementos son números válidos (incluyendo notación científica)
            if all(cellfun(@(x) ~isempty(x) && all(isstrprop(x, 'digit') | x == '-' | x == '.' | x == 'e' | x == 'E'), celulasNumeros))
                % Convertir a números
                app.bk = str2double(celulasNumeros);
                h = impz(app.ak, app.bk);
                stem(app.UIAxes,0:length(h)-1, h);
            else
                % Manejar el caso de caracteres no válidos
                msgbox('Enter only numbers separated by semicolons in scientific notation.', 'Error', 'error');
            end
            disp(app.bk);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Hacer la ecuación de diferencias:
            y = zeros(1, length(app.datasignal));
            x = app.datasignal(2,:);
            b = app.ak;
            a = app.bk;
            % Implementar el filtro IIR de forma explícita
            for n = 1:length(x)
                y(n) = b(1) * x(n);
                for k = 2:length(b)
                    if n - k + 1 > 0
                        y(n) = y(n) + b(k) * x(n - k + 1);
                    end
                end
                for k = 2:length(a)
                    if n - k + 1 > 0
                        y(n) = y(n) - a(k) * y(n - k + 1);
                    end
                end
            end
            yinsN = app.datasignal(1,1):1:length(y)-1 + app.datasignal(1,1);
            % Graficarla:
            % Calcular la respuesta al impulso
            saveEquation(app.Calling, app.channel, [y; yinsN], app.signal, app, b, a);
            %Cambiar color:
            app.DoneButton.BackgroundColor = [0, 0.86, 0];
        end

        % Menu selected function: DefaultfiltersMenu
        function DefaultfiltersMenuSelected(app, event)
            app.ak = [0.003279216306360205161751775193579305778
                0.016396081531801026676120613956300076097
                0.032792163063602053352241227912600152194
                0.032792163063602053352241227912600152194
                0.016396081531801026676120613956300076097
                0.003279216306360205161751775193579305778]';
            app.bk = [1
                -2.474416174978162796804781464743427932262
                2.811006311911582233875606107176281511784
                -1.703772240915468749733463482698425650597
                0.544432694888534296495663511450402438641
                -0.072315669102958557434845943134860135615]';
            cadena_resultante = sprintf('%d;', app.ak);
            app.akEditField.Value = cadena_resultante(1:end-1);
            cadena_resultante = sprintf('%d;', app.bk);
            app.bkEditField.Value = cadena_resultante(1:end-1);
            h = impz(app.ak, app.bk);
            stem(app.UIAxes,0:length(h)-1, h);
        end

        % Menu selected function: Filter2Menu
        function Filter2MenuSelected(app, event)
            app.ak = [ 0.268935721618647038955174366492428816855
                -1.344678608093235139264720601204317063093
                2.689357216186470722618651052471250295639
                -2.689357216186470722618651052471250295639
                1.344678608093235139264720601204317063093
                -0.268935721618647038955174366492428816855]';
            app.bk = [ 1
                -2.474416174978162796804781464743427932262
                2.811006311911582233875606107176281511784
                -1.703772240915468305644253632635809481144
                0.544432694888534185473361048934748396277
                -0.072315669102958543557058135320403380319]';
            cadena_resultante = sprintf('%d;', app.ak);
            app.akEditField.Value = cadena_resultante(1:end-1);
            cadena_resultante = sprintf('%d;', app.bk);
            app.bkEditField.Value = cadena_resultante(1:end-1);
            h = impz(app.ak, app.bk);
            stem(app.UIAxes,0:length(h)-1, h);
        end

        % Menu selected function: Filter3Menu
        function Filter3MenuSelected(app, event)
            app.ak = [ 0.01809893300751444500384934599424013868
                0
                -0.054296799022543335011548037982720416039
                0
                0.054296799022543335011548037982720416039
                0
                -0.01809893300751444500384934599424013868]';
            app.bk = [ 1
                -2.941867669925036121725270277238450944424
                4.702317288464325173436009208671748638153
                -4.634109656210405603360413806512951850891
                3.077447793658273589301188621902838349342
                -1.24661968102405040781377465464174747467
                0.278059917634545739062446045863907784224]';
            cadena_resultante = sprintf('%d;', app.ak);
            app.akEditField.Value = cadena_resultante(1:end-1);
            cadena_resultante = sprintf('%d;', app.bk);
            app.bkEditField.Value = cadena_resultante(1:end-1);
            h = impz(app.ak, app.bk);
            stem(app.UIAxes,0:length(h)-1, h);
        end

        % Menu selected function: Filter4Menu
        function Filter4MenuSelected(app, event)
            app.ak = [0.527624382501943212098183266789419576526
                -1.956538810076257295378354683634825050831
                4.001288117376634367872156872181221842766
                -4.90951938700698864437299562268890440464
                4.001288117376634367872156872181221842766
                -1.956538810076257295378354683634825050831
                0.527624382501943212098183266789419576526]';
            app.bk = [ 1
                -2.941867669925037009903689977363683283329
                4.702317288464326949792848608922213315964
                -4.634109656210408267895672906888648867607
                3.077447793658276253836447722278535366058
                -1.246619681024051962126009129860904067755
                0.278059917634546294173958358442177996039]';
            cadena_resultante = sprintf('%d;', app.ak);
            app.akEditField.Value = cadena_resultante(1:end-1);
            cadena_resultante = sprintf('%d;', app.bk);
            app.bkEditField.Value = cadena_resultante(1:end-1);
            h = impz(app.ak, app.bk);
            stem(app.UIAxes,0:length(h)-1, h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DefaultfiltersMenu = uimenu(app.UIFigure);
            app.DefaultfiltersMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultfiltersMenuSelected, true);
            app.DefaultfiltersMenu.Text = 'Default filters';
            app.Filter1Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter1Menu.Text = 'Filter 1';
            app.Filter2Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter2Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter2MenuSelected, true);
            app.Filter2Menu.Text = 'Filter 2';
            app.Filter3Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter3Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter3MenuSelected, true);
            app.Filter3Menu.Text = 'Filter 3';
            app.Filter4Menu = uimenu(app.DefaultfiltersMenu);
            app.Filter4Menu.MenuSelectedFcn = createCallbackFcn(app, @Filter4MenuSelected, true);
            app.Filter4Menu.Text = 'Filter 4';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'h(n)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.akEditFieldLabel = uilabel(app.UIFigure);
            app.akEditFieldLabel.Text = 'ak';
            app.akEditField = uieditfield(app.UIFigure, 'text');
            app.akEditField.ValueChangedFcn = createCallbackFcn(app, @akEditFieldValueChanged, true);
            app.akEditField.Value = '1;2;3;4;5';
            app.InfiniteImpulseResponseLabel = uilabel(app.UIFigure);
            app.InfiniteImpulseResponseLabel.Text = 'Infinite Impulse Response';
            app.bkEditFieldLabel = uilabel(app.UIFigure);
            app.bkEditFieldLabel.Text = 'bk';
            app.bkEditField = uieditfield(app.UIFigure, 'text');
            app.bkEditField.ValueChangedFcn = createCallbackFcn(app, @bkEditFieldValueChanged, true);
            app.bkEditField.Value = '1;2;3;4;5';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.EntertheimpulseresponsecoefficientsseparatedbyLabel = uilabel(app.UIFigure);
            app.EntertheimpulseresponsecoefficientsseparatedbyLabel.Text = 'Enter the impulse response coefficients separated by ";".';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = differencesEquation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end