classdef normalizacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StandardizationUIFigure  matlab.ui.Figure
        CancelButton             matlab.ui.control.Button
        ApplyButton              matlab.ui.control.Button
        RemovevoidsatstartandendCheckBox_2  matlab.ui.control.CheckBox
        RemovevoidsatstartandendCheckBox  matlab.ui.control.CheckBox
        Normalize2DropDown       matlab.ui.control.DropDown
        Normalize2DropDownLabel  matlab.ui.control.Label
        Normalize1DropDown       matlab.ui.control.DropDown
        Normalize1DropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Calling              % Description
        dataType1 = 0;       % Tipo de la Señal 1
        dataType2 = 0;       % Tipo de Señal 2
        dataSignal1 = [];    % Valores de la Señal 1
        dataSignal2 = [];    % Valores de la Señal 2
        changeModi = 0;      % Sea modificado una señal.
    end
    
    methods (Access = private)
        
        %% Función para normalizar la señal:
        function normalizeSignal(app)
            if isempty(app.dataSignal1) && (app.dataType1 ~= 10)
                msgbox('Signal 1 no data.', 'Error', 'error');
                return;
            else
                if app.dataType1 ~= 10
                    maxAbsValue = max(abs(app.dataSignal1(2,:)));
                    app.dataSignal1(2,:) = (1/maxAbsValue) * app.dataSignal1(2,:);
                end
            end
            if isempty(app.dataSignal2) && (app.dataType2 ~= 10)
                msgbox('Signal 2 no data.', 'Error', 'error');
                return;
            else
                if app.dataType2 ~= 10
                    maxAbsValue = max(abs(app.dataSignal2(2,:)));
                    app.dataSignal2(2,:) = (1/maxAbsValue) * app.dataSignal2(2,:);
                end
            end
            if app.dataType1 ~= 10
                graphSignal1(app.Calling, app.dataSignal1(1,:), app.dataSignal1(2,:), app.dataType1, ...
                    app.Calling.arraySamplingFrequencyGraph1(app.Normalize1DropDown.ValueIndex));
                app.changeModi = 1;
            end
            if app.dataType2 ~= 10
                graphSignal2(app.Calling, app.dataSignal2(1,:), app.dataSignal2(2,:), app.dataType2, ...
                    app.Calling.arraySamplingFrequencyGraph2(app.Normalize2DropDown.ValueIndex));
                app.changeModi = 1;
            end
            if ((app.dataType1 == 10) && (app.dataType2 == 10))
                msgbox('Choose a channel', 'Error', 'error');
                return
            end
        end

        %% Función para borrar los ceros al inicio y final de la señal.
        function erase(app, type)
            switch type
                case 1
                    if (app.RemovevoidsatstartandendCheckBox.Value == 1) && (app.dataType1 ~= 10)
                        indicesNoCero = find(app.dataSignal1(2,:), 1, 'first') : find(app.dataSignal1(2,:), 1, 'last');
                        auxArray1 = app.dataSignal1(2,:);
                        auxArray1n = app.dataSignal1(1,:);
                        app.dataSignal1 = [auxArray1n(indicesNoCero); auxArray1(indicesNoCero)];
                        graphSignal1(app.Calling, app.dataSignal1(1,:), app.dataSignal1(2,:), app.dataType1, ...
                            app.Calling.arraySamplingFrequencyGraph1(app.Normalize1DropDown.ValueIndex));
                    end
                case 2
                     if (app.RemovevoidsatstartandendCheckBox_2.Value == 1) && (app.dataType2 ~= 10)
                        indicesNoCero2 = find(app.dataSignal2(2, :), 1, 'first') : find(app.dataSignal2(2, :), 1, 'last');
                        auxArray2 = app.dataSignal2(2,:);
                        auxArray2n = app.dataSignal2(1,:);
                        app.dataSignal2 = [auxArray2n(indicesNoCero2); auxArray2(indicesNoCero2)];
                        graphSignal2(app.Calling, app.dataSignal2(1,:), app.dataSignal2(2,:), app.dataType2, ...
                            app.Calling.arraySamplingFrequencyGraph2(app.Normalize2DropDown.ValueIndex));
                    end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, type1, type2)
            movegui(app.StandardizationUIFigure, 'center');
            app.Calling = caller;
            switch type1
                case 1
                    app.Normalize1DropDown.ValueIndex = 1;
                    app.dataSignal1 = app.Calling.dataSignal1_1;
                    if isempty(app.dataSignal1)
                        app.Normalize1DropDown.ValueIndex = 6;
                        app.dataType1 = 10;
                    else
                        app.dataType1 = 0;
                    end
                case 2
                    app.Normalize1DropDown.ValueIndex = 2;
                    app.dataSignal1 = app.Calling.dataSignal1_2;
                    if isempty(app.dataSignal1)
                        app.Normalize1DropDown.ValueIndex = 6;
                        app.dataType1 = 10;
                    else
                        app.dataType1 = 1;
                    end
                case 3
                    app.Normalize1DropDown.ValueIndex = 3;
                    app.dataSignal1 = app.Calling.dataSignal1_3;
                    if isempty(app.dataSignal1)
                        app.Normalize1DropDown.ValueIndex = 6;
                        app.dataType1 = 10;
                    else
                        app.dataType1 = 3;
                    end
                case 4
                    app.Normalize1DropDown.ValueIndex = 4;
                    app.dataSignal1 = app.Calling.dataSignal1_4;
                    if isempty(app.dataSignal1)
                        app.Normalize1DropDown.ValueIndex = 6;
                        app.dataType1 = 10;
                    else
                        app.dataType1 = 4;
                    end
                case 5
                    app.Normalize1DropDown.ValueIndex = 5;
                    app.dataSignal1 = app.Calling.dataSignalResult1;
                    if isempty(app.dataSignal1)
                        app.Normalize1DropDown.ValueIndex = 6;
                        app.dataType1 = 10;
                    else
                        app.dataType1 = 5;
                    end
            end
            switch type2
                case 1
                    app.Normalize2DropDown.ValueIndex = 1;
                    app.dataSignal2 = app.Calling.dataSignal2_1;
                    if isempty(app.dataSignal2)
                        app.Normalize2DropDown.ValueIndex = 6;
                        app.dataType2 = 10;
                    else
                        app.dataType2 = 0;
                    end
                case 2
                    app.Normalize2DropDown.ValueIndex = 2;
                    app.dataSignal2 = app.Calling.dataSignal2_2;
                    if isempty(app.dataSignal2)
                        app.Normalize2DropDown.ValueIndex = 6;
                        app.dataType2 = 10;
                    else
                        app.dataType2 = 1;
                    end
                case 3
                    app.Normalize2DropDown.ValueIndex = 3;
                    app.dataSignal2 = app.Calling.dataSignal2_3;
                    if isempty(app.dataSignal2)
                        app.Normalize2DropDown.ValueIndex = 6;
                        app.dataType2 = 10;
                    else
                        app.dataType2 = 3;
                    end
                case 4
                    app.Normalize2DropDown.ValueIndex = 4;
                    app.dataSignal2 = app.Calling.dataSignal2_4;
                    if isempty(app.dataSignal2)
                        app.Normalize2DropDown.ValueIndex = 6;
                        app.dataType2 = 10;
                    else
                        app.dataType2 = 4;
                    end
                case 5
                    app.Normalize2DropDown.ValueIndex = 5;
                    app.dataSignal2 = app.Calling.dataSignalResult2;
                    if isempty(app.dataSignal2)
                        app.Normalize2DropDown.ValueIndex = 6;
                        app.dataType2 = 10;
                    else
                        app.dataType2 = 5;
                    end
            end
        end

        % Value changed function: Normalize1DropDown
        function Normalize1DropDownValueChanged(app, event)
            value = app.Normalize1DropDown.Value;
            switch value
                case "Channel 1"
                    app.dataType1 = 0;
                    app.dataSignal1 = app.Calling.dataSignal1_1;
                case "Channel 2"
                    app.dataType1 = 1;
                    app.dataSignal1 = app.Calling.dataSignal1_2;
                case "Out 3"
                    app.dataType1 = 3;
                    app.dataSignal1 = app.Calling.dataSignal1_3;
                case "Out 4"
                    app.dataType1 = 4;
                    app.dataSignal1 = app.Calling.dataSignal1_4;
                case "Result 1"
                    app.dataType1 = 5;
                    app.dataSignal1 = app.Calling.dataSignalResult1;
                case "Ninguno"
                    app.dataSignal1 = [];
                    app.dataType1 = 10;
            end
        end

        % Value changed function: Normalize2DropDown
        function Normalize2DropDownValueChanged(app, event)
            value = app.Normalize2DropDown.Value;
            switch value
                case "Channel 1"
                    app.dataType2 = 0;
                    app.dataSignal2 = app.Calling.dataSignal2_1;
                case "Channel 2"
                    app.dataType2 = 1;
                    app.dataSignal2 = app.Calling.dataSignal2_2;
                case "Out 3"
                    app.dataType2 = 3;
                    app.dataSignal2 = app.Calling.dataSignal2_3;
                case "Out 4"
                    app.dataType2 = 4;
                    app.dataSignal2 = app.Calling.dataSignal2_4;
                case "Result 2"
                    app.dataType2 = 5;
                    app.dataSignal2 = app.Calling.dataSignalResult2;
                case "Ninguno"
                    app.dataSignal2 = [];
                    app.dataType2 = 10;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            normalizeSignal(app);
            if(app.RemovevoidsatstartandendCheckBox.Value == 1)
                 erase(app, 1);
            end
            if(app.RemovevoidsatstartandendCheckBox_2.Value == 1)
                erase(app, 2);
            end
            if(app.changeModi == 1)
                delete(app);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StandardizationUIFigure = uifigure('Visible', 'off');
            app.StandardizationUIFigure.Name = 'Standardization';
            app.Normalize1DropDownLabel = uilabel(app.StandardizationUIFigure);
            app.Normalize1DropDownLabel.Text = 'Normalize 1';
            app.Normalize1DropDown = uidropdown(app.StandardizationUIFigure);
            app.Normalize1DropDown.Items = {'Channel 1', 'Channel 2', 'Out 3', 'Out 4', 'Result 1', 'Ninguno'};
            app.Normalize1DropDown.ValueChangedFcn = createCallbackFcn(app, @Normalize1DropDownValueChanged, true);
            app.Normalize1DropDown.Value = 'Channel 1';
            app.Normalize2DropDownLabel = uilabel(app.StandardizationUIFigure);
            app.Normalize2DropDownLabel.Text = 'Normalize 2';
            app.Normalize2DropDown = uidropdown(app.StandardizationUIFigure);
            app.Normalize2DropDown.Items = {'Channel 1', 'Channel 2', 'Out 3', 'Out 4', 'Result 2', 'Ninguno'};
            app.Normalize2DropDown.ValueChangedFcn = createCallbackFcn(app, @Normalize2DropDownValueChanged, true);
            app.Normalize2DropDown.Value = 'Channel 1';
            app.RemovevoidsatstartandendCheckBox = uicheckbox(app.StandardizationUIFigure);
            app.RemovevoidsatstartandendCheckBox.Text = 'Remove voids at start and end';
            app.RemovevoidsatstartandendCheckBox_2 = uicheckbox(app.StandardizationUIFigure);
            app.RemovevoidsatstartandendCheckBox_2.Text = 'Remove voids at start and end';
            app.ApplyButton = uibutton(app.StandardizationUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.StandardizationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.StandardizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normalizacion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StandardizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StandardizationUIFigure)
        end
    end
end